classdef NanoClusterSize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ULabel                 matlab.ui.control.Label
        uIdxEdit               matlab.ui.control.NumericEditField
        VLabel                 matlab.ui.control.Label
        vIdxEdit               matlab.ui.control.NumericEditField
        WLabel                 matlab.ui.control.Label
        wIdxEdit               matlab.ui.control.NumericEditField
        RadiusAEditFieldLabel  matlab.ui.control.Label
        radiusEdit             matlab.ui.control.NumericEditField
        PreviewButton          matlab.ui.control.Button
        OKButton               matlab.ui.control.Button
        latticeAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingApp % main app object
        
        % atom sites and coords
        unitCellAtomSites
        
        cellLengths
        cellAngles
        uvw
        convMat
        atomCoords
        
        radius
    end
    
    methods (Access = private)
        
        function Plot2dUnitCell(app)
            scatter(app.latticeAxes, app.atomCoords(3, :), app.atomCoords(4, :), "filled");
            initVertexSites = [0, 0, 0; 0, 0, 1; 0, 1, 0; 0, 1, 1; 1, 0, 0; 1, 0, 1; 1, 1, 0; 1, 1, 1]';
            adjVertexSites1 = [0, 0, 1; 0, 0, 0; 0, 1, 1; 0, 1, 0; 1, 0, 1; 1, 0, 0; 1, 1, 1; 1, 1, 0]';
            adjVertexSites2 = [0, 1, 0; 0, 1, 1; 0, 0, 0; 0, 0, 1; 1, 1, 0; 1, 1, 1; 1, 0, 0; 1, 0, 1]';
            adjVertexSites3 = [1, 0, 0; 1, 0, 1; 1, 1, 0; 1, 1, 1; 0, 0, 0; 0, 0, 1; 0, 1, 0; 0, 1, 1]';
            
            initCellVertexes = app.convMat * initVertexSites;
            adjCellVertexes1 = app.convMat * adjVertexSites1;
            adjCellVertexes2 = app.convMat * adjVertexSites2;
            adjCellVertexes3 = app.convMat * adjVertexSites3;
            
            hold(app.latticeAxes, 'on');
            vertexNum = size(initCellVertexes, 2);
            for vertexIdx = 1 : vertexNum
                line(app.latticeAxes, [initCellVertexes(1, vertexIdx), adjCellVertexes1(1, vertexIdx)],...
                    [initCellVertexes(2, vertexIdx), adjCellVertexes1(2, vertexIdx)], 'Color', 'blue');
                
                line(app.latticeAxes, [initCellVertexes(1, vertexIdx), adjCellVertexes2(1, vertexIdx)],...
                    [initCellVertexes(2, vertexIdx), adjCellVertexes2(2, vertexIdx)], 'Color', 'blue');
                
                line(app.latticeAxes, [initCellVertexes(1, vertexIdx), adjCellVertexes3(1, vertexIdx)],...
                    [initCellVertexes(2, vertexIdx), adjCellVertexes3(2, vertexIdx)], 'Color', 'blue');
            end
            hold(app.latticeAxes, 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, cellLengths, cellAngles, unitCellAtomSites)
            app.callingApp = mainapp;
            app.cellLengths = cellLengths;
            app.cellAngles = cellAngles;
            app.unitCellAtomSites = AddEquivAtomSites(unitCellAtomSites);
            
            app.uvw = [0, 0, 1];
            app.uIdxEdit.Value = app.uvw(1);
            app.vIdxEdit.Value = app.uvw(2);
            app.wIdxEdit.Value = app.uvw(3);
            
            app.convMat = ConversionMatrix_hkl(app.cellLengths, app.cellAngles, app.uvw);
            app.atomCoords = app.unitCellAtomSites;
            app.atomCoords(3 : 5, :) = app.convMat * app.atomCoords(3 : 5, :);
            
            app.RadiusAEditFieldLabel.Text = ['Radius (', char(197), ')'];
            app.radius = 10;
            app.radiusEdit.Value = app.radius;
            
            Plot2dUnitCell(app);
        end

        % Value changed function: radiusEdit
        function radiusEditValueChanged(app, event)
            value = app.radiusEdit.Value;
            if value > 0
                app.radius = value;
            else
                msgbox('Please enter a positive value!');
                app.radiusEdit.Value = app.radius;
            end
        end

        % Value changed function: uIdxEdit
        function uIdxEditValueChanged(app, event)
            value = app.uIdxEdit.Value;
            if (any([value, app.uvw(2), app.uvw(3)])) && (mod(value, 1) == 0)
                app.uvw(1) = value;
            else
                app.uIdxEdit.Value = app.uvw(1);
                msgbox('Invalid input!');
            end
        end

        % Value changed function: vIdxEdit
        function vIdxEditValueChanged(app, event)
            value = app.vIdxEdit.Value;
            if (any([app.uvw(1), value, app.uvw(3)])) && (mod(value, 1) == 0)
                app.uvw(2) = value;
            else
                app.vIdxEdit.Value = app.uvw(2);
                msgbox('Invalid input!');
            end
        end

        % Value changed function: wIdxEdit
        function wIdxEditValueChanged(app, event)
            value = app.wIdxEdit.Value;
            if (any([app.uvw(1), app.uvw(2), value])) && (mod(value, 1) == 0)
                app.uvw(3) = value;
            else
                app.wIdxEdit.Value = app.uvw(3);
                msgbox('Invalid input!');
            end
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            app.atomCoords = CreateNanoCluster_hkl(app.unitCellAtomSites,...
                app.cellLengths, app.cellAngles, app.uvw, app.radius);
            
            scatter(app.latticeAxes, app.atomCoords(3, :), app.atomCoords(4, :), 'filled');
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.atomCoords = CreateNanoCluster_hkl(app.unitCellAtomSites,...
                app.cellLengths, app.cellAngles, app.uvw, app.radius);
            
            scatter(app.latticeAxes, app.atomCoords(3, :), app.atomCoords(4, :), 'filled');
            
            UpdateFromNanoClusterDialog(app.callingApp, app.radius, app.atomCoords);
            app.callingApp.NanoclusterMenu.Enable = 'on';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.NanoclusterMenu.Enable = 'on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ULabel = uilabel(app.UIFigure);
            app.ULabel.Text = 'U';
            app.uIdxEdit = uieditfield(app.UIFigure, 'numeric');
            app.uIdxEdit.ValueChangedFcn = createCallbackFcn(app, @uIdxEditValueChanged, true);
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.vIdxEdit = uieditfield(app.UIFigure, 'numeric');
            app.vIdxEdit.ValueChangedFcn = createCallbackFcn(app, @vIdxEditValueChanged, true);
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = 'W';
            app.wIdxEdit = uieditfield(app.UIFigure, 'numeric');
            app.wIdxEdit.ValueChangedFcn = createCallbackFcn(app, @wIdxEditValueChanged, true);
            app.RadiusAEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusAEditFieldLabel.Text = 'Radius (A)';
            app.radiusEdit = uieditfield(app.UIFigure, 'numeric');
            app.radiusEdit.Limits = [0 Inf];
            app.radiusEdit.ValueChangedFcn = createCallbackFcn(app, @radiusEditValueChanged, true);
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.latticeAxes = uiaxes(app.UIFigure);
            title(app.latticeAxes, 'x-y view')
            xlabel(app.latticeAxes, 'X')
            ylabel(app.latticeAxes, 'Y')
            zlabel(app.latticeAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NanoClusterSize(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end