classdef RectSampleSize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ULabel           matlab.ui.control.Label
        uIdxEdit         matlab.ui.control.NumericEditField
        VLabel           matlab.ui.control.Label
        vIdxEdit         matlab.ui.control.NumericEditField
        WLabel           matlab.ui.control.Label
        wIdxEdit         matlab.ui.control.NumericEditField
        WidthALabel      matlab.ui.control.Label
        widthEdit        matlab.ui.control.NumericEditField
        ThicknessALabel  matlab.ui.control.Label
        thicknessEdit    matlab.ui.control.NumericEditField
        OKButton         matlab.ui.control.Button
        previewButton    matlab.ui.control.Button
        latticeAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingApp % main app object
        
        specimenType
        % atom sites and coords
        unitCellAtomSites
        expandedAtomSites
        
        cellLengths
        cellAngles
        uvw
        convMat
        atomCoords
        
        width
        thickness
    end
    
    methods (Access = private)
        
        function Plot2dUnitCell(app)
            scatter(app.latticeAxes, app.atomCoords(3, :), app.atomCoords(4, :), "filled");
            initVertexSites = [0, 0, 0; 0, 0, 1; 0, 1, 0; 0, 1, 1; 1, 0, 0; 1, 0, 1; 1, 1, 0; 1, 1, 1]';
            adjVertexSites1 = [0, 0, 1; 0, 0, 0; 0, 1, 1; 0, 1, 0; 1, 0, 1; 1, 0, 0; 1, 1, 1; 1, 1, 0]';
            adjVertexSites2 = [0, 1, 0; 0, 1, 1; 0, 0, 0; 0, 0, 1; 1, 1, 0; 1, 1, 1; 1, 0, 0; 1, 0, 1]';
            adjVertexSites3 = [1, 0, 0; 1, 0, 1; 1, 1, 0; 1, 1, 1; 0, 0, 0; 0, 0, 1; 0, 1, 0; 0, 1, 1]';
            
            initCellVertexes = app.convMat * initVertexSites;
            adjCellVertexes1 = app.convMat * adjVertexSites1;
            adjCellVertexes2 = app.convMat * adjVertexSites2;
            adjCellVertexes3 = app.convMat * adjVertexSites3;
            
            hold(app.latticeAxes, 'on');
            vertexNum = size(initCellVertexes, 2);
            for vertexIdx = 1 : vertexNum
                line(app.latticeAxes, [initCellVertexes(1, vertexIdx), adjCellVertexes1(1, vertexIdx)],...
                    [initCellVertexes(2, vertexIdx), adjCellVertexes1(2, vertexIdx)], 'Color', 'blue');
                
                line(app.latticeAxes, [initCellVertexes(1, vertexIdx), adjCellVertexes2(1, vertexIdx)],...
                    [initCellVertexes(2, vertexIdx), adjCellVertexes2(2, vertexIdx)], 'Color', 'blue');
                
                line(app.latticeAxes, [initCellVertexes(1, vertexIdx), adjCellVertexes3(1, vertexIdx)],...
                    [initCellVertexes(2, vertexIdx), adjCellVertexes3(2, vertexIdx)], 'Color', 'blue');
            end
            hold(app.latticeAxes, 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, cellLengths, cellAngles, unitCellAtomSites, specimenType)
            % assign main app object
            app.callingApp = mainapp;
            app.specimenType = specimenType;
            app.unitCellAtomSites = AddEquivAtomSites(unitCellAtomSites);
            app.expandedAtomSites = app.unitCellAtomSites;
            app.cellLengths = cellLengths;
            app.cellAngles = cellAngles;
            
            app.uvw = [0, 0, 1];
            app.uIdxEdit.Value = app.uvw(1);
            app.vIdxEdit.Value = app.uvw(2);
            app.wIdxEdit.Value = app.uvw(3);
            
            app.convMat = ConversionMatrix_hkl(app.cellLengths, app.cellAngles, app.uvw);
            app.atomCoords = app.expandedAtomSites;
            app.atomCoords(3 : 5, :) = app.convMat * app.atomCoords(3 : 5, :);
            
            app.WidthALabel.Text = ['Width (', char(197), ')'];
            app.width = 10;
            app.widthEdit.Value = app.width;
            app.ThicknessALabel.Text = ['Thickness (', char(197), ')'];
            app.thickness = 10;
            app.thicknessEdit.Value = app.thickness;
            
            Plot2dUnitCell(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            switch app.specimenType
                case SampleType.period
                    app.atomCoords = CellExpand_hkl(app.unitCellAtomSites,...
                        app.cellLengths, app.cellAngles, app.uvw, app.width);
                case SampleType.bulk
                    app.atomCoords = BulkCellExpand_hkl(app.unitCellAtomSites,...
                        app.cellLengths, app.cellAngles, app.uvw, app.width, app.thickness);
                otherwise
                    % do nothing
            end
            
            app.atomCoords(:, (app.atomCoords(3, :) < -app.width / 2) |...
                (app.atomCoords(3, :) > app.width / 2)) = [];
            app.atomCoords(:, (app.atomCoords(4, :) < -app.width / 2) |...
                (app.atomCoords(4, :) > app.width / 2)) = [];
            
            UpdateFromRectSampleSizeDialog(app.callingApp, app.uvw, app.width,...
                app.thickness, app.atomCoords, app.specimenType);
            
            app.callingApp.RectangleUVWMenu.Enable = 'on';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.RectangleUVWMenu.Enable = 'on';
            delete(app);
        end

        % Value changed function: thicknessEdit
        function thicknessEditValueChanged(app, event)
            value = app.thicknessEdit.Value;
            if value > 0
                app.thickness = value;
            else
                app.thicknessEdit.Value = app.thickness;
                msgbox('Please enter a positive value!');
            end
        end

        % Value changed function: widthEdit
        function widthEditValueChanged(app, event)
            value = app.widthEdit.Value;
            if value > 0
                app.width = value;
            else
                app.widthEdit.Value = app.width;
                msgbox('Please enter a positive value!');
            end
        end

        % Value changed function: uIdxEdit
        function uIdxEditValueChanged(app, event)
            value = app.uIdxEdit.Value;
            if (any([value, app.uvw(2), app.uvw(3)])) && (mod(value, 1) == 0)
                app.uvw(1) = value;
            else
                app.uIdxEdit.Value = app.uvw(1);
                msgbox('Invalid input!');
            end
        end

        % Value changed function: vIdxEdit
        function vIdxEditValueChanged(app, event)
            value = app.vIdxEdit.Value;
            if (any([app.uvw(1), value, app.uvw(3)])) && (mod(value, 1) == 0)
                app.uvw(2) = value;
            else
                app.vIdxEdit.Value = app.uvw(2);
                msgbox('Invalid input!');
            end
        end

        % Value changed function: wIdxEdit
        function wIdxEditValueChanged(app, event)
            value = app.wIdxEdit.Value;
            if (any([app.uvw(1), app.uvw(2), value])) && (mod(value, 1) == 0)
                app.uvw(3) = value;
            else
                app.wIdxEdit.Value = app.uvw(3);
                msgbox('Invalid input!');
            end
        end

        % Button pushed function: previewButton
        function previewButtonPushed(app, event)
            switch app.specimenType
                case SampleType.period
                    app.atomCoords = CellExpand_hkl(app.unitCellAtomSites,...
                        app.cellLengths, app.cellAngles, app.uvw, app.width);
                case SampleType.bulk
                    app.atomCoords = BulkCellExpand_hkl(app.unitCellAtomSites,...
                        app.cellLengths, app.cellAngles, app.uvw, app.width, app.thickness);
                otherwise
                    % do nothing
            end
            
            app.atomCoords(:, (app.atomCoords(3, :) < -app.width / 2) |...
                (app.atomCoords(3, :) > app.width / 2)) = [];
            app.atomCoords(:, (app.atomCoords(4, :) < -app.width / 2) |...
                (app.atomCoords(4, :) > app.width / 2)) = [];
            
            scatter(app.latticeAxes, app.atomCoords(3, :), app.atomCoords(4, :), 'filled');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ULabel = uilabel(app.UIFigure);
            app.ULabel.Text = 'U';
            app.uIdxEdit = uieditfield(app.UIFigure, 'numeric');
            app.uIdxEdit.ValueChangedFcn = createCallbackFcn(app, @uIdxEditValueChanged, true);
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.vIdxEdit = uieditfield(app.UIFigure, 'numeric');
            app.vIdxEdit.ValueChangedFcn = createCallbackFcn(app, @vIdxEditValueChanged, true);
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = 'W';
            app.wIdxEdit = uieditfield(app.UIFigure, 'numeric');
            app.wIdxEdit.ValueChangedFcn = createCallbackFcn(app, @wIdxEditValueChanged, true);
            app.WidthALabel = uilabel(app.UIFigure);
            app.WidthALabel.Text = 'Width (A)';
            app.widthEdit = uieditfield(app.UIFigure, 'numeric');
            app.widthEdit.Limits = [0 Inf];
            app.widthEdit.ValueChangedFcn = createCallbackFcn(app, @widthEditValueChanged, true);
            app.ThicknessALabel = uilabel(app.UIFigure);
            app.ThicknessALabel.Text = 'Thickness (A)';
            app.thicknessEdit = uieditfield(app.UIFigure, 'numeric');
            app.thicknessEdit.Limits = [0 Inf];
            app.thicknessEdit.ValueChangedFcn = createCallbackFcn(app, @thicknessEditValueChanged, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.previewButton = uibutton(app.UIFigure, 'push');
            app.previewButton.ButtonPushedFcn = createCallbackFcn(app, @previewButtonPushed, true);
            app.previewButton.Text = 'Preview';
            app.latticeAxes = uiaxes(app.UIFigure);
            title(app.latticeAxes, 'x-y view')
            xlabel(app.latticeAxes, 'X')
            ylabel(app.latticeAxes, 'Y')
            zlabel(app.latticeAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RectSampleSize(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end