classdef NanoDiff < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NanoDiffUIFigure                matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadxyzfileMenu                 matlab.ui.container.Menu
        LoadCIFMenu                     matlab.ui.container.Menu
        saveUnadjDiffPattMenu           matlab.ui.container.Menu
        unadjDiffPattAsTextMenu         matlab.ui.container.Menu
        unadjDiffPattAsTiffMenu         matlab.ui.container.Menu
        unadjDiffPattAsJpegMenu         matlab.ui.container.Menu
        SaveajusteddiffractionpatternMenu  matlab.ui.container.Menu
        adjDiffPattAsTiffMenu           matlab.ui.container.Menu
        adjDiffPattAsJpegMenu           matlab.ui.container.Menu
        saveOtfPhaseMenu                matlab.ui.container.Menu
        otfPhaseAsTextMenu              matlab.ui.container.Menu
        otfPhaseAsTiffMenu              matlab.ui.container.Menu
        otfPhaseAsJpegMenu              matlab.ui.container.Menu
        removeSpecimenMenu              matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        editElePropMenu                 matlab.ui.container.Menu
        BuildMenu                       matlab.ui.container.Menu
        RectangleMenu                   matlab.ui.container.Menu
        NanoclusterMenu                 matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        diffractionTab                  matlab.ui.container.Tab
        VoltagekVEditFieldLabel         matlab.ui.control.Label
        voltageEdit                     matlab.ui.control.NumericEditField
        diffractButton                  matlab.ui.control.Button
        ProbeUpdateTimeLabel            matlab.ui.control.Label
        probeLastUpdateTimeEdit         matlab.ui.control.EditField
        stemPanel                       matlab.ui.container.Panel
        AperturesizemradEditFieldLabel  matlab.ui.control.Label
        apertSizeEdit                   matlab.ui.control.NumericEditField
        probeCenterLabel                matlab.ui.control.Label
        MovingstepLabel                 matlab.ui.control.Label
        probeXyStepEdit                 matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        probeXEdit                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        probeYEdit                      matlab.ui.control.NumericEditField
        probeLeftButton                 matlab.ui.control.Button
        probeUpButton                   matlab.ui.control.Button
        probeRightButton                matlab.ui.control.Button
        probeDownButton                 matlab.ui.control.Button
        backToOriginButton              matlab.ui.control.Button
        showProbePositionButton         matlab.ui.control.StateButton
        stemDiffLamp                    matlab.ui.control.Lamp
        ModeSwitchLabel                 matlab.ui.control.Label
        diffModeSwitch                  matlab.ui.control.Switch
        temPanel                        matlab.ui.container.Panel
        temDiffLamp                     matlab.ui.control.Lamp
        PolarEditFieldLabel             matlab.ui.control.Label
        polarEdit                       matlab.ui.control.NumericEditField
        AzimuthalEditFieldLabel         matlab.ui.control.Label
        azimEdit                        matlab.ui.control.NumericEditField
        beamtiltLabel                   matlab.ui.control.Label
        StepEditFieldLabel_4            matlab.ui.control.Label
        polarStepEdit                   matlab.ui.control.NumericEditField
        StepLabel                       matlab.ui.control.Label
        azimStepEdit                    matlab.ui.control.NumericEditField
        polarMinusButton                matlab.ui.control.Button
        polarPlusButton                 matlab.ui.control.Button
        azimAntiClockButton             matlab.ui.control.Button
        azimClockButton                 matlab.ui.control.Button
        polarUnitLabel                  matlab.ui.control.Label
        azimUnitLabel                   matlab.ui.control.Label
        probeAxes                       matlab.ui.control.UIAxes
        aberrationTab                   matlab.ui.container.Tab
        aberrValueLabel                 matlab.ui.control.Label
        aberrAngleLabel                 matlab.ui.control.Label
        C1Label                         matlab.ui.control.Label
        A1Label                         matlab.ui.control.Label
        A2Label                         matlab.ui.control.Label
        B2Label                         matlab.ui.control.Label
        C3Label                         matlab.ui.control.Label
        A3Label                         matlab.ui.control.Label
        S3Label                         matlab.ui.control.Label
        A4Label                         matlab.ui.control.Label
        C1Edit                          matlab.ui.control.NumericEditField
        A1Edit                          matlab.ui.control.NumericEditField
        A2Edit                          matlab.ui.control.NumericEditField
        B2Edit                          matlab.ui.control.NumericEditField
        C3Edit                          matlab.ui.control.NumericEditField
        A3Edit                          matlab.ui.control.NumericEditField
        S3Edit                          matlab.ui.control.NumericEditField
        A4Edit                          matlab.ui.control.NumericEditField
        C1UnitDropDown                  matlab.ui.control.DropDown
        A1UnitDropDown                  matlab.ui.control.DropDown
        A2UnitDropDown                  matlab.ui.control.DropDown
        B2UnitDropDown                  matlab.ui.control.DropDown
        C3UnitDropDown                  matlab.ui.control.DropDown
        A3UnitDropDown                  matlab.ui.control.DropDown
        S3UnitDropDown                  matlab.ui.control.DropDown
        A4UnitDropDown                  matlab.ui.control.DropDown
        A1AngleEdit                     matlab.ui.control.NumericEditField
        A2AngleEdit                     matlab.ui.control.NumericEditField
        B2AngleEdit                     matlab.ui.control.NumericEditField
        A3AngleEdit                     matlab.ui.control.NumericEditField
        S3AngleEdit                     matlab.ui.control.NumericEditField
        A4AngleEdit                     matlab.ui.control.NumericEditField
        C1AngleLabel                    matlab.ui.control.Label
        C3AngleLabel                    matlab.ui.control.Label
        generateObjTransFuncButton      matlab.ui.control.Button
        OTFUpdateTimeLabel              matlab.ui.control.Label
        otfLastUpadateTimeEdit          matlab.ui.control.EditField
        otfPhaseAxes                    matlab.ui.control.UIAxes
        diffImagePanel                  matlab.ui.container.Panel
        MinimumEditFieldLabel           matlab.ui.control.Label
        minGrayValueEdit                matlab.ui.control.NumericEditField
        StepEditFieldLabel              matlab.ui.control.Label
        minGrayValueStepEdit            matlab.ui.control.NumericEditField
        minGrayValueMinusButton         matlab.ui.control.Button
        minGrayValueAddButton           matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        minGrayValueSlider              matlab.ui.control.Slider
        MaximumEditFieldLabel           matlab.ui.control.Label
        maxGrayValueEdit                matlab.ui.control.NumericEditField
        StepEditFieldLabel_2            matlab.ui.control.Label
        maxGrayValueStepEdit            matlab.ui.control.NumericEditField
        maxGrayValueMinusButton         matlab.ui.control.Button
        maxGrayValueAddButton           matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        maxGrayValueSlider              matlab.ui.control.Slider
        GammaEditFieldLabel             matlab.ui.control.Label
        gammaValueEdit                  matlab.ui.control.NumericEditField
        gammaValueMinusButton           matlab.ui.control.Button
        gammaValueAddButton             matlab.ui.control.Button
        StepEditFieldLabel_3            matlab.ui.control.Label
        gammaValueStepEdit              matlab.ui.control.NumericEditField
        addCirleButton                  matlab.ui.control.StateButton
        CircleradiusmradEditFieldLabel  matlab.ui.control.Label
        circleRadiusEdit                matlab.ui.control.NumericEditField
        diffractionAxes                 matlab.ui.control.UIAxes
        histogramAxes                   matlab.ui.control.UIAxes
        SpecimenPanel                   matlab.ui.container.Panel
        AtomNumberEditFieldLabel        matlab.ui.control.Label
        atomNumEdit                     matlab.ui.control.NumericEditField
        UnitcellconstantsTextAreaLabel  matlab.ui.control.Label
        unitCellConstTextArea           matlab.ui.control.TextArea
        atomSiteAxes                    matlab.ui.control.UIAxes
        OperationSamplingPanel          matlab.ui.container.Panel
        TiltStepdegreeEditFieldLabel    matlab.ui.control.Label
        outPlaneTiltStepEdit            matlab.ui.control.NumericEditField
        TiltStepdegreeEditFieldLabel_2  matlab.ui.control.Label
        inPlaneTiltStepEdit             matlab.ui.control.NumericEditField
        AngleEditFieldLabel             matlab.ui.control.Label
        upDownTiltAngleEdit             matlab.ui.control.NumericEditField
        downButton                      matlab.ui.control.Button
        upButton                        matlab.ui.control.Button
        leftButton                      matlab.ui.control.Button
        rightButton                     matlab.ui.control.Button
        AngleEditFieldLabel_2           matlab.ui.control.Label
        leftRightTiltAngleEdit          matlab.ui.control.NumericEditField
        antiClockButton                 matlab.ui.control.Button
        clockButton                     matlab.ui.control.Button
        AngleEditFieldLabel_3           matlab.ui.control.Label
        inPlaneTiltAngleEdit            matlab.ui.control.NumericEditField
        BackToInitialButton             matlab.ui.control.Button
        PixelsizeAngsEditFieldLabel     matlab.ui.control.Label
        pixelSizeEdit                   matlab.ui.control.NumericEditField
        PixelnumberperdimensionEditFieldLabel  matlab.ui.control.Label
        dimPixelNumEdit                 matlab.ui.control.NumericEditField
        MaxanglemradEditFieldLabel      matlab.ui.control.Label
        maxAngleEdit                    matlab.ui.control.NumericEditField
        AngularprecisionmradEditFieldLabel  matlab.ui.control.Label
        angPrecEdit                     matlab.ui.control.NumericEditField
        defaultSampButton               matlab.ui.control.Button
        projPotButton                   matlab.ui.control.Button
        SpecimenprojectedpotentialpreparedLabel  matlab.ui.control.Label
        projPotPrepareLamp              matlab.ui.control.Lamp
        ProjectedpotentialupdatetimeLabel  matlab.ui.control.Label
        projPotLastUpdateTimeEdit       matlab.ui.control.EditField
        hklOrientButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        % file
        fileType
        
        % crystal matrix: [type; prop; x; y; z]
        crystalMatrix
        initCrystalMatrix
        
        cellLengths
        cellAngles
        unitCellAtomSiteMat
        atomSiteMat
        convMat
        
        specimenWidth
        specimenThickness
        
        typeNum
        typeList
        typeOrientAtomSiteMat
        typeOrientCrysMat
        
        % crystal operation properties
        leftRightTilt
        upDownTilt
        outPlaneTiltStep
        
        inPlaneTilt
        inPlaneTiltStep
        
        % sampling
        pixelSize
        dimPixelNum
        sideLength
        
        maxAngle
        angPrec
        
        % tem param
        voltage
        wavLen
        
        polarTiltAngle % unit: mrad
        polarTiltStep % unit: mrad
        
        azimTiltAngle % unit: degree
        azimTiltStep % unit: degree
        
        % stem param
        probeX
        probeY
        probeXyStep
        
        % objective lens param
        apertSize
        C1
        A1
        A2
        B2
        C3
        A3
        S3
        A4
        
        A1Angle
        A2Angle
        B2Angle
        A3Angle
        S3Angle
        A4Angle
        
        % objective transfer function phase and STEM probe
        objTransFunc
        demoObjTransFuncPhase
        demoProbe
        
        % specimen
        projPotMat
        sliceDist
        stackNum
        extraSliceNum
        
        % diffraction pattern
        diffPatt
        initGrayDiffPatt
        adjGrayDiffPatt
        
        % histogram
        initGrayCounts
        initBinLocations
        minGrayValue
        maxGrayValue
        gammaValue
        
        minGrayValueStep
        maxGrayValueStep
        gammaValueStep
        
        % external dependent apps
        ElePropDialogApp
        RectSampleDialogApp
    end
    
    methods (Access = private)
        
        function PlotCrystalFitScreen(app)
            if ~isempty(app.crystalMatrix)
                maxObjectX = max(app.crystalMatrix(3, :));
                minObjectX = min(app.crystalMatrix(3, :));
                
                maxObjectY = max(app.crystalMatrix(4, :));
                minObjectY = min(app.crystalMatrix(4, :));
                
                xCoordRange = maxObjectX - minObjectX;
                yCoordRange = maxObjectY - minObjectY;
                
                app.sideLength = max(xCoordRange, yCoordRange);
                app.pixelSize = app.sideLength / app.dimPixelNum;
                app.pixelSizeEdit.Value = app.pixelSize;
                
                app.maxAngle = app.wavLen / (2 * app.pixelSize) * 1e3;
                app.maxAngleEdit.Value = app.maxAngle;
                
                app.angPrec = app.wavLen / app.sideLength * 1e3;
                app.angPrecEdit.Value = app.angPrec;
                
                % shift the center of the object to the origin
                app.crystalMatrix(3, :) = app.crystalMatrix(3, :) - (maxObjectX + minObjectX) / 2;
                app.crystalMatrix(4, :) = app.crystalMatrix(4, :) - (maxObjectY + minObjectY) / 2;
                
                scatter(app.atomSiteAxes, app.crystalMatrix(3, :), app.crystalMatrix(4, :), 75,...
                    "blue", "filled");
                app.atomSiteAxes.XLim = [-app.sideLength, app.sideLength] / 2;
                app.atomSiteAxes.YLim = [-app.sideLength, app.sideLength] / 2;
            else
                msgbox('Please load xyz file first!');
            end
        end
    
        function PlotCrystal(app)
            if ~isempty(app.crystalMatrix)
                maxObjectX = max(app.crystalMatrix(3, :));
                minObjectX = min(app.crystalMatrix(3, :));
                
                maxObjectY = max(app.crystalMatrix(4, :));
                minObjectY = min(app.crystalMatrix(4, :));
                
                % shift the center of the object to the origin
                app.crystalMatrix(3, :) = app.crystalMatrix(3, :) - (maxObjectX + minObjectX) / 2;
                app.crystalMatrix(4, :) = app.crystalMatrix(4, :) - (maxObjectY + minObjectY) / 2;
                
                scatter(app.atomSiteAxes, app.crystalMatrix(3, :), app.crystalMatrix(4, :), 75,...
                    "blue", "filled");
                app.atomSiteAxes.XLim = [-app.sideLength, app.sideLength] / 2;
                app.atomSiteAxes.YLim = [-app.sideLength, app.sideLength] / 2;
            else
                msgbox('Please load xyz file first!');
            end
        end
        
        function UpdateHistogram(app)
            if ~isempty(app.initGrayDiffPatt)
                [app.initGrayCounts, app.initBinLocations] = imhist(app.initGrayDiffPatt);
                tmpMaxGrayCount = max(app.initGrayCounts, [], 'all');
                bar(app.histogramAxes, app.initBinLocations, app.initGrayCounts / tmpMaxGrayCount);
                hold(app.histogramAxes, 'on');
                adjRange = linspace(app.minGrayValue, app.maxGrayValue, 128);
                adjGrayVal = (adjRange - app.minGrayValue).^app.gammaValue;
                adjGrayVal = adjGrayVal / (app.maxGrayValue - app.minGrayValue)^app.gammaValue;
                plot(app.histogramAxes, adjRange, adjGrayVal);
                hold(app.histogramAxes, 'off');
                app.histogramAxes.XLim = [0, 1];
            else
                msgbox('Please generate a diffraction pattern!');
            end
        end
        
        function UpdateDispDiffPatt(app)
            if ~isempty(app.initGrayDiffPatt)
                app.adjGrayDiffPatt = imadjust(app.initGrayDiffPatt,...
                        [app.minGrayValue app.maxGrayValue], [0 1], app.gammaValue);
                if app.addCirleButton.Value
                    circR = ceil(app.circleRadiusEdit.Value / app.angPrec);
                    circX = floor(app.dimPixelNum / 2);
                    dispImg = insertShape(app.adjGrayDiffPatt, "Circle", [circX, circX, circR],...
                        "LineWidth", 5, "Color", "red");
                    imshow(dispImg, 'Parent', app.diffractionAxes);
                else
                    imshow(app.adjGrayDiffPatt, 'Parent', app.diffractionAxes);
                end
            else
                 msgbox('Please generate a diffraction pattern!');
            end
        end
        
        function PlotProbeOnCrystal(app)
            if ~isempty(app.crystalMatrix)
                scatter(app.atomSiteAxes, app.crystalMatrix(3, :), app.crystalMatrix(4, :), 75,...
                    "blue", "filled");
                app.atomSiteAxes.XLim = [-app.sideLength, app.sideLength] / 2;
                app.atomSiteAxes.YLim = [-app.sideLength, app.sideLength] / 2;
                
                hold(app.atomSiteAxes, 'on');
                plot(app.atomSiteAxes, app.probeX, app.probeY, '+', 'LineWidth', 2, 'Color', 'r');
                hold(app.atomSiteAxes, 'off');
            else
                msgbox('Please load xyz file first!');
                app.probeX = 0;
                app.probeXEdit.Value = app.probeX;
                app.probeY = 0;
                app.probeYEdit.Value = app.probeY;
            end
        end
    
        function CreateObjTransFuncPhase(app)
            % prepare aberrations for MultiPhaseError_X_v2()
            aberr{1} = [app.C1, app.A1];
            aberr{2} = [app.B2, app.A2];
            aberr{3} = [app.C3, app.S3, app.A3];
            aberr{4} = [0, 0, app.A4];
            aberr{5} = [0, 0, 0, 0];
            
            aberrAngle{1} = [0, app.A1Angle];
            aberrAngle{2} = [app.B2Angle, app.A2Angle];
            aberrAngle{3} = [0, app.S3Angle, app.A3Angle];
            aberrAngle{4} = [0, 0, app.A4Angle];
            aberrAngle{5} = [0, 0, 0, 0];
            
            tmpObjTransFuncPhase = MultiAberrPhaseError_X_v2(aberr, aberrAngle, app.wavLen,...
                app.sideLength, app.sideLength, app.dimPixelNum, app.dimPixelNum);
            
            tmpAperture = CircApert_X(app.sideLength, app.sideLength, app.dimPixelNum,...
                app.dimPixelNum, app.wavLen, app.apertSize);
            
            tmpDemoObjTransFuncPhase = tmpObjTransFuncPhase .* tmpAperture;
            demoPixelNum = 2 * floor(app.apertSize / app.angPrec) + 2;
            demoRange =...
                floor(app.dimPixelNum / 2) - demoPixelNum / 2 + 1 :...
                floor(app.dimPixelNum / 2) + demoPixelNum / 2;
            if demoPixelNum > 0.9 * app.dimPixelNum
                app.demoObjTransFuncPhase = wrapTo2Pi(tmpDemoObjTransFuncPhase);
            else
                app.demoObjTransFuncPhase = wrapTo2Pi(tmpDemoObjTransFuncPhase(demoRange, demoRange));
            end
            
            app.objTransFunc = exp(1i * tmpObjTransFuncPhase) .* tmpAperture;
            
            tmpDemoProbe = GenerateProbe_X(app.objTransFunc, 0, 0, app.sideLength,...
                app.sideLength, app.dimPixelNum, app.dimPixelNum);
            app.demoProbe = mat2gray(abs(tmpDemoProbe.^2));
        end
        
        function RemoveSpecimen(app)
            % empty the crystal matrix and projected potential matrix
            app.crystalMatrix = [];
            app.initCrystalMatrix = [];
            app.projPotMat = [];
            app.projPotPrepareLamp.Color = [1 0 0];
            
            % Leave the diffraction patterns undeleted, in case that they have not been saved
            
            % clear the figure content on atomSiteAxes
            cla(app.atomSiteAxes);
        end
        
        function PlotTiltBeam(app)
            cla(app.probeAxes);
            beamPointX = app.polarTiltAngle * cosd(app.azimTiltAngle);
            beamPointY = app.polarTiltAngle * sind(app.azimTiltAngle);
            if app.polarTiltAngle > 1e-2
                hold(app.probeAxes, 'on');
                tempAngle = 0 : 0.01 : 2 * pi;
                plot(app.probeAxes, app.polarTiltAngle * cos(tempAngle), ...
                    app.polarTiltAngle * sin(tempAngle));
                line(app.probeAxes, [0, beamPointX], [0, beamPointY]);
                scatter(app.probeAxes, beamPointX, beamPointY);
                text(app.probeAxes, 1.1 * beamPointX, 1.1 * beamPointY,...
                    ['(', num2str(app.polarTiltAngle, '%.2f'), ', ',...
                    num2str(app.azimTiltAngle, '%.2f'), ')']);
                hold(app.probeAxes, 'off');
                app.probeAxes.XLim = 2.0 * [-app.polarTiltAngle, app.polarTiltAngle];
                app.probeAxes.YLim = 2.0 * [-app.polarTiltAngle, app.polarTiltAngle];
            else
                hold(app.probeAxes, 'on');
                scatter(app.probeAxes, beamPointX, beamPointY);
                text(app.probeAxes, 0.05, 0.05,...
                    ['(', num2str(app.polarTiltAngle, '%.2f'), ', ',...
                    num2str(app.azimTiltAngle, '%.2f'), ')']);
                hold(app.probeAxes, 'off');
            end
        end
        
        function SortCrysMatByType(app)
            [app.typeOrientCrysMat, app.typeNum, app.typeList] = app.SortByAtomType(app.crystalMatrix);
        end
        
        function SortAtomSiteMatByType(app)
            [app.typeOrientAtomSiteMat, app.typeNum, app.typeList] = app.SortByAtomType(app.atomSiteMat);
        end
        
        function SelectedAreaDiffract(app)
            interCoeff = InteractionCoefficient(app.voltage);
            sliceNum = length(app.sliceDist);
            transFuncMat = 1i * ones(app.dimPixelNum, app.dimPixelNum, sliceNum);
            for sliceIdx = 1 : sliceNum
                transFuncMat(:, :, sliceIdx) = exp(1i * interCoeff *...
                    app.projPotMat(:, :, sliceIdx) / 1e3);
            end
            
            Lx = app.sideLength;
            dx = app.pixelSize;
            x = -Lx / 2 : dx : Lx / 2 - dx;
            [xGrid, yGrid] = meshgrid(x);
            
            fxShift = app.polarTiltAngle * 1e-3 * cosd(app.azimTiltAngle) / app.wavLen;
            fyShift = app.polarTiltAngle * 1e-3 * sind(app.azimTiltAngle) / app.wavLen;
            
            wave = exp(1i * 2 * pi * (fxShift * xGrid + fyShift * yGrid));
            wave = multislice_X(wave, app.voltage, Lx, Lx, transFuncMat, app.sliceDist, app.stackNum);
            
            if app.extraSliceNum ~= 0
                wave = multislice_X(wave, app.voltage, Lx, Lx, transFuncMat(:, :, 1 : app.extraSliceNum),...
                    app.sliceDist(1 : app.extraSliceNum), 1);
            end
            
            wave = ifftshift(fft2(fftshift(wave))) * dx^2;
            app.diffPatt = log(1 + 0.1 * abs(wave.^2));
            app.initGrayDiffPatt = mat2gray(app.diffPatt);
        end
        
        function ConvergentBeamDiffract(app)
            interCoeff = InteractionCoefficient(app.voltage);
            sliceNum = length(app.sliceDist);
            transFuncMat = 1i * ones(app.dimPixelNum, app.dimPixelNum, sliceNum);
            for sliceIdx = 1 : sliceNum
                transFuncMat(:, :, sliceIdx) = exp(1i * interCoeff *...
                    app.projPotMat(:, :, sliceIdx) / 1e3);
            end
            
            wave = GenerateProbe_X(app.objTransFunc, app.probeX, app.probeY,...
                app.sideLength, app.sideLength, app.dimPixelNum, app.dimPixelNum);
            
            wave = multislice_X(wave, app.voltage, app.sideLength, app.sideLength,...
                transFuncMat, app.sliceDist, app.stackNum);
            
            if app.extraSliceNum ~= 0
                wave = multislice_X(wave, app.voltage, app.sideLength, app.sideLength,...
                    transFuncMat(:, :, 1 : app.extraSliceNum), app.sliceDist(1 : app.extraSliceNum), 1);
            end
            
            wave = ifftshift(fft2(fftshift(wave)));
            app.diffPatt = log(1 + 0.1 * abs(wave.^2));
            app.initGrayDiffPatt = mat2gray(app.diffPatt);
        end
        
        function DispCellInfo(app)
            if (~isempty(app.cellLengths)) && (~isempty(app.cellAngles))
                dispText = {['a = ', num2str(app.cellLengths(1), '%.4f'), char(197)];...
                    ['b = ', num2str(app.cellLengths(2), '%.4f'), char(197)];...
                    ['c = ', num2str(app.cellLengths(3), '%.4f'), char(197)];...
                    [char(945), ' = ', num2str(app.cellAngles(1), '%.4f'), char(176)];...
                    [char(946), ' = ', num2str(app.cellAngles(2), '%.4f'), char(176)];...
                    [char(947), ' = ', num2str(app.cellAngles(3), '%.4f'), char(176)]};
                app.unitCellConstTextArea.Value = dispText;
            end
        end
    end
    
    methods (Access = public)
        
        function UpdateFromElePropEditDialog(app, editedCrysMat)
            app.crystalMatrix = editedCrysMat;
        end
        
        function UpdateFromRectSampleSizeDialog(app, hkl, width, thickness)
            
        end
    end
    
    methods(Static)
        function str = UpdateTime()
            updateTime = clock;
            str = [num2str(updateTime(4)), ' : ', num2str(updateTime(5)),...
                ' : ', num2str(updateTime(6), '%.3f')];
        end
        
        function [sortedMat, typeNum, typeList] = SortByAtomType(initMat)
            atomNum = size(initMat, 2);
            
            if atomNum >= 1
                typeList = uniquetol(initMat(1, :)');
                typeNum = length(typeList);
                sortedMat = cell(typeNum, 1);
                for typeIdx = 1 : typeNum
                    sortedMat{typeIdx, 1} = initMat(:, initMat(1, :) == typeList(typeIdx));
                end
            else
                sortedMat = {};
                typeNum = 0;
                typeList = [];
                msgbox('Null input!');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize file type
            app.fileType = 'none';
            
            % initialize crystal matrices
            app.crystalMatrix = [];
            app.initCrystalMatrix = [];
            
            app.cellLengths = [];
            app.cellAngles = [];
            app.unitCellAtomSiteMat = [];
            app.atomSiteMat = [];
            app.convMat = [];
            
            % initialize tilting angles
            app.outPlaneTiltStep = 10;
            app.outPlaneTiltStepEdit.Value = app.outPlaneTiltStep;
            
            app.leftRightTilt = 0;
            app.leftRightTiltAngleEdit.Value = app.leftRightTilt;
            app.upDownTilt = 0;
            app.upDownTiltAngleEdit.Value = app.upDownTilt;
            
            app.inPlaneTiltStep = 10;
            app.inPlaneTiltStepEdit.Value = app.inPlaneTiltStep;
            
            app.inPlaneTilt = 0;
            app.inPlaneTiltAngleEdit.Value = app.inPlaneTilt;
            
            % edit the TEM/STEM mode switch
            app.diffModeSwitch.Value = app.diffModeSwitch.Items{1};
            app.temDiffLamp.Color = [0 1 0];
            app.stemDiffLamp.Color = [1 0 0];
            
            % initialize TEM instrumental parameters
            app.voltage = 200;
            app.wavLen = HighEnergyWavLen_X(app.voltage);
            
            app.polarTiltAngle = 0;
            app.polarEdit.Value = app.polarTiltAngle;
            app.polarTiltStep = 1;
            app.polarStepEdit.Value = app.polarTiltStep;
            
            app.azimTiltAngle = 0;
            app.azimEdit.Value = app.azimTiltAngle;
            app.azimTiltStep = 10;
            app.azimStepEdit.Value = app.azimTiltStep;
            
            PlotTiltBeam(app);
            
            % initialize STEM instrumental parameters
            app.voltageEdit.Value = app.voltage;
            app.probeX = 0;
            app.probeXEdit.Value = app.probeX;
            app.probeY = 0;
            app.probeYEdit.Value = app.probeY;
            app.probeXyStep = 0.1;
            app.probeXyStepEdit.Value = app.probeXyStep;
            
            % initialize objective lens param
            app.apertSize = 20;
            app.apertSizeEdit.Value = app.apertSize;
            
            app.C1 = 0;
            app.C1Edit.Value = app.C1;
            app.C1UnitDropDown.Items = {'mm', [char(956), 'm'], 'nm', 'pm'};
            app.C1UnitDropDown.Value = app.C1UnitDropDown.Items{1};
            
            app.A1 = 0;
            app.A1Edit.Value = app.A1;
            app.A1UnitDropDown.Items = {'mm', [char(956), 'm'], 'nm', 'pm'};
            app.A1UnitDropDown.Value = app.A1UnitDropDown.Items{1};
            
            app.A2 = 0;
            app.A2Edit.Value = app.A2;
            app.A2UnitDropDown.Items = {'mm', [char(956), 'm'], 'nm', 'pm'};
            app.A2UnitDropDown.Value = app.A2UnitDropDown.Items{1};
            
            app.B2 = 0;
            app.B2Edit.Value = app.B2;
            app.B2UnitDropDown.Items = {'mm', [char(956), 'm'], 'nm', 'pm'};
            app.B2UnitDropDown.Value = app.B2UnitDropDown.Items{1};
            
            app.C3 = 0;
            app.C3Edit.Value = app.C3;
            app.C3UnitDropDown.Items = {'mm', [char(956), 'm'], 'nm', 'pm'};
            app.C3UnitDropDown.Value = app.C3UnitDropDown.Items{1};
            
            app.A3 = 0;
            app.A3Edit.Value = app.A3;
            app.A3UnitDropDown.Items = {'mm', [char(956), 'm'], 'nm', 'pm'};
            app.A3UnitDropDown.Value = app.A3UnitDropDown.Items{1};
            
            app.S3 = 0;
            app.S3Edit.Value = app.S3;
            app.S3UnitDropDown.Items = {'mm', [char(956), 'm'], 'nm', 'pm'};
            app.S3UnitDropDown.Value = app.S3UnitDropDown.Items{1};
            
            app.A4 = 0;
            app.A4Edit.Value = app.A4;
            app.A4UnitDropDown.Items = {'mm', [char(956), 'm'], 'nm', 'pm'};
            app.A4UnitDropDown.Value = app.A4UnitDropDown.Items{1};
            
            app.A1Angle = 0;
            app.A1AngleEdit.Value = app.A1Angle;
            app.A2Angle = 0;
            app.A2AngleEdit.Value = app.A2Angle;
            app.B2Angle = 0;
            app.B2AngleEdit.Value = app.B2Angle;
            app.A3Angle = 0;
            app.A3AngleEdit.Value = app.A3Angle;
            app.S3Angle = 0;
            app.S3AngleEdit.Value = app.S3Angle;
            app.A4Angle = 0;
            app.A4AngleEdit.Value = app.A4Angle;
            
            % initialize objective transfer function phase and demo probe
            app.objTransFunc = [];
            app.demoObjTransFuncPhase = [];
            app.demoProbe = [];
            
            % initialize sampling parameters
            app.angPrec = 0.1;
            app.angPrecEdit.Value = app.angPrec;
            
            app.dimPixelNum = 512;
            app.dimPixelNumEdit.Value = app.dimPixelNum;
            
            app.PixelsizeAngsEditFieldLabel.Text = ['Pixel size (', char(197), ')'];
            app.PixelsizeAngsEditFieldLabel.HorizontalAlignment = 'left';
            app.pixelSize = app.wavLen / (app.angPrec * 1e-3) / app.dimPixelNum;
            app.pixelSizeEdit.Value = app.pixelSize;
            
            app.sideLength = app.pixelSize * app.dimPixelNum;
            
            app.maxAngle = app.angPrec * app.dimPixelNum;
            app.maxAngleEdit.Value = app.maxAngle;
            
            % initialize projPotMat and diffPatt
            app.projPotPrepareLamp.Color = [1 0 0];
            app.projPotMat = [];
            app.sliceDist = [];
            app.stackNum = 1;
            app.extraSliceNum = 0;
            
            app.diffPatt = [];
            app.initGrayDiffPatt = [];
            app.adjGrayDiffPatt = [];
            app.initGrayCounts = [];
            app.initBinLocations = [];
            
            % edit the angular circle:
            app.circleRadiusEdit.Value = app.maxAngle / 2;
            
            app.minGrayValue = 0;
            app.minGrayValueEdit.Value = app.minGrayValue;
            app.maxGrayValue = 1;
            app.maxGrayValueEdit.Value = app.maxGrayValue;
            app.gammaValue = 1;
            app.gammaValueEdit.Value = app.gammaValue;
            
            app.minGrayValueSlider.Value = app.minGrayValue;
            app.maxGrayValueSlider.Value = app.maxGrayValue;
            
            app.minGrayValueStep = 0.1;
            app.minGrayValueStepEdit.Value = app.minGrayValueStep;
            app.maxGrayValueStep = 0.1;
            app.maxGrayValueStepEdit.Value = app.maxGrayValueStep;
            app.gammaValueStep = 0.1;
            app.gammaValueStepEdit.Value = app.gammaValueStep;
        end

        % Menu selected function: LoadxyzfileMenu
        function LoadxyzfileMenuSelected(app, event)
            [file, path] = uigetfile('*.xyz');
            if ~(isequal(file, 0) || isequal(path, 0))
                app.fileType = 'xyz';
                app.initCrystalMatrix = [];
                app.crystalMatrix = [];
                
                [tmpTypeList, tmpXyzList] = ReadCrystalMakerXyz(fullfile(path, file));
                
                atomNum = length(tmpTypeList);
                app.crystalMatrix = ones(5, atomNum);
                app.crystalMatrix(1, :) = tmpTypeList;
                app.crystalMatrix(3 : 5, :) = tmpXyzList;
                app.initCrystalMatrix = app.crystalMatrix;
                
                SortCrysMatByType(app);
                
                PlotCrystalFitScreen(app);
            end
        end

        % Value changed function: upDownTiltAngleEdit
        function upDownTiltAngleEditValueChanged(app, event)
            if ~isempty(app.crystalMatrix)
                newTilt = app.upDownTiltAngleEdit.Value;
                tiltChange = newTilt - app.upDownTilt;
                app.crystalMatrix = CrystalMatrixRotXd(app.crystalMatrix, -tiltChange);
                app.upDownTilt = newTilt;
                
                if app.showProbePositionButton.Value
                    PlotProbeOnCrystal(app);
                else
                    PlotCrystal(app);
                end
            else
                msgbox('Please load xyz file first!');
                app.upDownTiltAngleEdit.Value = app.upDownTilt;
            end
        end

        % Value changed function: leftRightTiltAngleEdit
        function leftRightTiltAngleEditValueChanged(app, event)
            if ~isempty(app.crystalMatrix)
                newTilt = app.leftRightTiltAngleEdit.Value;
                tiltChange = newTilt - app.leftRightTilt;
                app.crystalMatrix = CrystalMatrixRotYd(app.crystalMatrix, tiltChange);
                app.leftRightTilt = newTilt;
                
                if app.showProbePositionButton.Value
                    PlotProbeOnCrystal(app);
                else
                    PlotCrystal(app);
                end
            else
                msgbox('Please load xyz file first!');
                app.leftRightTiltAngleEdit.Value = app.leftRightTilt;
            end
        end

        % Value changed function: inPlaneTiltAngleEdit
        function inPlaneTiltAngleEditValueChanged(app, event)
            if ~isempty(app.crystalMatrix)
                newTilt = app.inPlaneTiltAngleEdit.Value;
                tiltChange = newTilt - app.inPlaneTilt;
                app.crystalMatrix = CrystalMatrixRotZd(app.crystalMatrix, -tiltChange);
                app.inPlaneTilt = newTilt;
                
                if app.showProbePositionButton.Value
                    PlotProbeOnCrystal(app);
                else
                    PlotCrystal(app);
                end
            else
                msgbox('Please load xyz file first!');
                app.inPlaneTiltAngleEdit.Value = app.inPlaneTilt;
            end
        end

        % Button pushed function: BackToInitialButton
        function BackToInitialButtonPushed(app, event)
            if ~isempty(app.crystalMatrix)
                app.crystalMatrix = app.initCrystalMatrix;
                app.upDownTilt = 0;
                app.leftRightTilt = 0;
                app.inPlaneTilt = 0;
                
                app.upDownTiltAngleEdit.Value = app.upDownTilt;
                app.leftRightTiltAngleEdit.Value = app.leftRightTilt;
                app.inPlaneTiltAngleEdit.Value = app.inPlaneTilt;
                
                if app.showProbePositionButton.Value
                    PlotProbeOnCrystal(app);
                else
                    PlotCrystal(app);
                end
            else
                msgbox('Please load xyz file first!');
            end
        end

        % Value changed function: outPlaneTiltStepEdit
        function outPlaneTiltStepEditValueChanged(app, event)
            value = app.outPlaneTiltStepEdit.Value;
            app.outPlaneTiltStep = value;
        end

        % Value changed function: inPlaneTiltStepEdit
        function inPlaneTiltStepEditValueChanged(app, event)
            value = app.inPlaneTiltStepEdit.Value;
            app.inPlaneTiltStep = value;
        end

        % Button pushed function: upButton
        function upButtonPushed(app, event)
            if ~isempty(app.crystalMatrix)
                app.crystalMatrix = CrystalMatrixRotXd(app.crystalMatrix, -app.outPlaneTiltStep);
                app.upDownTilt = app.upDownTilt + app.outPlaneTiltStep;
                app.upDownTiltAngleEdit.Value = app.upDownTilt;
                
                if app.showProbePositionButton.Value
                    PlotProbeOnCrystal(app);
                else
                    PlotCrystal(app);
                end
            else
                msgbox('Please load xyz file first!');
            end
        end

        % Button pushed function: downButton
        function downButtonPushed(app, event)
            if ~isempty(app.crystalMatrix)
                app.crystalMatrix = CrystalMatrixRotXd(app.crystalMatrix, app.outPlaneTiltStep);
                app.upDownTilt = app.upDownTilt - app.outPlaneTiltStep;
                app.upDownTiltAngleEdit.Value = app.upDownTilt;
                
                if app.showProbePositionButton.Value
                    PlotProbeOnCrystal(app);
                else
                    PlotCrystal(app);
                end
            else
                msgbox('Please load xyz file first!');
            end
        end

        % Button pushed function: leftButton
        function leftButtonPushed(app, event)
            if ~isempty(app.crystalMatrix)
                app.crystalMatrix = CrystalMatrixRotYd(app.crystalMatrix, -app.outPlaneTiltStep);
                app.leftRightTilt = app.leftRightTilt - app.outPlaneTiltStep;
                app.leftRightTiltAngleEdit.Value = app.leftRightTilt;
                
                if app.showProbePositionButton.Value
                    PlotProbeOnCrystal(app);
                else
                    PlotCrystal(app);
                end
            else
                msgbox('Please load xyz file first!');
            end
        end

        % Button pushed function: rightButton
        function rightButtonPushed(app, event)
            if ~isempty(app.crystalMatrix)
                app.crystalMatrix = CrystalMatrixRotYd(app.crystalMatrix, app.outPlaneTiltStep);
                app.leftRightTilt = app.leftRightTilt + app.outPlaneTiltStep;
                app.leftRightTiltAngleEdit.Value = app.leftRightTilt;
                
                if app.showProbePositionButton.Value
                    PlotProbeOnCrystal(app);
                else
                    PlotCrystal(app);
                end
            else
                msgbox('Please load xyz file first!');
            end
        end

        % Button pushed function: antiClockButton
        function antiClockButtonPushed(app, event)
            if ~isempty(app.crystalMatrix)
                app.crystalMatrix = CrystalMatrixRotZd(app.crystalMatrix, app.inPlaneTiltStep);
                app.inPlaneTilt = app.inPlaneTilt - app.inPlaneTiltStep;
                app.inPlaneTiltAngleEdit.Value = app.inPlaneTilt;
                
                if app.showProbePositionButton.Value
                    PlotProbeOnCrystal(app);
                else
                    PlotCrystal(app);
                end
            else
                msgbox('Please load xyz file first!');
            end
        end

        % Button pushed function: clockButton
        function clockButtonPushed(app, event)
            if ~isempty(app.crystalMatrix)
                app.crystalMatrix = CrystalMatrixRotZd(app.crystalMatrix, -app.inPlaneTiltStep);
                app.inPlaneTilt = app.inPlaneTilt + app.inPlaneTiltStep;
                app.inPlaneTiltAngleEdit.Value = app.inPlaneTilt;
                
                if app.showProbePositionButton.Value
                    PlotProbeOnCrystal(app);
                else
                    PlotCrystal(app);
                end
            else
                msgbox('Please load xyz file first!');
            end
        end

        % Button pushed function: defaultSampButton
        function defaultSampButtonPushed(app, event)
            if ~isempty(app.crystalMatrix)
                maxObjectX = max(app.crystalMatrix(3, :));
                minObjectX = min(app.crystalMatrix(3, :));
                
                maxObjectY = max(app.crystalMatrix(4, :));
                minObjectY = min(app.crystalMatrix(4, :));
                
                xCoordRange = maxObjectX - minObjectX;
                yCoordRange = maxObjectY - minObjectY;
                
                maxCoordRange = max(xCoordRange, yCoordRange);
                
                app.pixelSize = 0.1;
                app.pixelSizeEdit.Value = app.pixelSize;
                
                app.maxAngle = app.wavLen / (2 * app.pixelSize) * 1e3;
                app.maxAngleEdit.Value = app.maxAngle;
                
                tmpScaleFac = 1.5;
                tmpSideLength = tmpScaleFac * maxCoordRange;
                tmpAngPrec = app.wavLen / tmpSideLength;
                while tmpAngPrec > 0.1e-3
                    tmpScaleFac = tmpScaleFac + 0.1;
                    tmpSideLength = tmpScaleFac * maxCoordRange;
                    tmpAngPrec = app.wavLen / tmpSideLength;
                end
                app.dimPixelNum = floor(app.maxAngle * 1e-3 / tmpAngPrec) + 1;
                app.dimPixelNumEdit.Value = app.dimPixelNum;
                
                app.angPrec = app.maxAngle / app.dimPixelNum;
                app.angPrecEdit.Value = app.angPrec;
                
                app.sideLength = app.pixelSize * app.dimPixelNum;
                
                % shift the center of the object to the origin
                app.crystalMatrix(3, :) = app.crystalMatrix(3, :) - (maxObjectX + minObjectX) / 2;
                app.crystalMatrix(4, :) = app.crystalMatrix(4, :) - (maxObjectY + minObjectY) / 2;
                
                scatter(app.atomSiteAxes, app.crystalMatrix(3, :), app.crystalMatrix(4, :), '.');
                app.atomSiteAxes.XLim = [-app.sideLength, app.sideLength] / 2;
                app.atomSiteAxes.YLim = [-app.sideLength, app.sideLength] / 2;
            else
                msgbox('Please load xyz file first!');
            end
        end

        % Value changed function: maxAngleEdit
        function maxAngleEditValueChanged(app, event)
            value = app.maxAngleEdit.Value;
            app.maxAngle = value;
            app.pixelSize = app.wavLen / (2e-3 * app.maxAngle);
            app.pixelSizeEdit.Value = app.pixelSize;
            
            app.sideLength = app.dimPixelNum * app.pixelSize;
            app.angPrec = app.wavLen / app.sideLength * 1e3;
            app.angPrecEdit.Value = app.angPrec;
            
            if ~isempty(app.crystalMatrix)
                scatter(app.atomSiteAxes, app.crystalMatrix(3, :), app.crystalMatrix(4, :), '.');
                app.atomSiteAxes.XLim = [-app.sideLength, app.sideLength] / 2;
                app.atomSiteAxes.YLim = [-app.sideLength, app.sideLength] / 2;
            end
        end

        % Value changed function: angPrecEdit
        function angPrecEditValueChanged(app, event)
            value = app.angPrecEdit.Value;
            app.angPrec = value;
            
            app.maxAngle = app.dimPixelNum * app.angPrec;
            app.maxAngleEdit.Value = app.maxAngle;
            
            app.sideLength = app.wavLen / (app.angPrec * 1e-3);
            app.pixelSize = app.wavLen / (2e-3 * app.maxAngle);
            app.pixelSizeEdit.Value = app.pixelSize;
            
            if ~isempty(app.crystalMatrix)
                scatter(app.atomSiteAxes, app.crystalMatrix(3, :), app.crystalMatrix(4, :), '.');
                app.atomSiteAxes.XLim = [-app.sideLength, app.sideLength] / 2;
                app.atomSiteAxes.YLim = [-app.sideLength, app.sideLength] / 2;
            end
        end

        % Value changed function: pixelSizeEdit
        function pixelSizeEditValueChanged(app, event)
            value = app.pixelSizeEdit.Value;
            app.pixelSize = value;
            
            app.sideLength = app.pixelSize * app.dimPixelNum;
            app.angPrec = app.wavLen / app.sideLength * 1e3;
            app.angPrecEdit.Value = app.angPrec;
            
            app.maxAngle = app.wavLen / (2 * app.pixelSize) *1e3;
            app.maxAngleEdit.Value = app.maxAngle;
            
            if ~isempty(app.crystalMatrix)
                scatter(app.atomSiteAxes, app.crystalMatrix(3, :), app.crystalMatrix(4, :), '.');
                app.atomSiteAxes.XLim = [-app.sideLength, app.sideLength] / 2;
                app.atomSiteAxes.YLim = [-app.sideLength, app.sideLength] / 2;
            end
        end

        % Value changed function: dimPixelNumEdit
        function dimPixelNumEditValueChanged(app, event)
            value = app.dimPixelNumEdit.Value;
            app.dimPixelNum = value;
            
            app.sideLength = app.pixelSize * app.dimPixelNum;
            app.angPrec = app.wavLen / app.sideLength * 1e3;
            app.angPrecEdit.Value = app.angPrec;
            
            if ~isempty(app.crystalMatrix)
                scatter(app.atomSiteAxes, app.crystalMatrix(3, :), app.crystalMatrix(4, :), '.');
                app.atomSiteAxes.XLim = [-app.sideLength, app.sideLength] / 2;
                app.atomSiteAxes.YLim = [-app.sideLength, app.sideLength] / 2;
            end
        end

        % Button pushed function: projPotButton
        function projPotButtonPushed(app, event)
            if ~isempty(app.crystalMatrix)
                app.projPotMat = [];
                app.sliceDist = [];
                [app.projPotMat, app.sliceDist] = NanoDiffAppProjPotMat(app.crystalMatrix,...
                    app.pixelSize, app.dimPixelNum);
                
                msgbox('Specimen projected potential done!');
                app.projPotPrepareLamp.Color = [0 1 0];
                
                app.projPotLastUpdateTimeEdit.Value = app.UpdateTime();
            else
                msgbox('Please load xyz file first!');
            end
        end

        % Value changed function: minGrayValueEdit
        function minGrayValueEditValueChanged(app, event)
            value = app.minGrayValueEdit.Value;
            if (value < 0) || (value > app.maxGrayValue)
                msgbox('Invalid operation!');
                app.minGrayValueEdit.Value = app.minGrayValue;
            else
                app.minGrayValue = value;
                app.minGrayValueSlider.Value = app.minGrayValue;
                if ~isempty(app.initGrayDiffPatt)
                    UpdateDispDiffPatt(app);
                    UpdateHistogram(app);
                end
            end
        end

        % Value changed function: maxGrayValueEdit
        function maxGrayValueEditValueChanged(app, event)
            value = app.maxGrayValueEdit.Value;
            if (value > 1) || (value < app.minGrayValue)
                msgbox('Invalid operation!');
                app.maxGrayValueEdit.Value = app.maxGrayValue;
            else
                app.maxGrayValue = value;
                app.maxGrayValueSlider.Value = app.maxGrayValue;
                if ~isempty(app.initGrayDiffPatt)
                    UpdateDispDiffPatt(app);
                    UpdateHistogram(app);
                end
            end
        end

        % Value changed function: gammaValueEdit
        function gammaValueEditValueChanged(app, event)
            value = app.gammaValueEdit.Value;
            if value < 0
                msgbox('Invalid operation!');
                app.gammaValueEdit.Value = app.gammaValue;
            else
                app.gammaValue = value;
                if ~isempty(app.initGrayDiffPatt)
                    UpdateDispDiffPatt(app);
                    UpdateHistogram(app);
                end
            end
        end

        % Value changed function: minGrayValueSlider
        function minGrayValueSliderValueChanged(app, event)
            value = app.minGrayValueSlider.Value;
            if (value < 0) || (value > app.maxGrayValue)
                msgbox('Invalid operation!');
                app.minGrayValueSlider.Value = app.minGrayValue;
            else
                app.minGrayValue = value;
                app.minGrayValueEdit.Value = app.minGrayValue;
                if ~isempty(app.initGrayDiffPatt)
                    UpdateDispDiffPatt(app);
                    UpdateHistogram(app);               
                end
            end
        end

        % Value changed function: maxGrayValueSlider
        function maxGrayValueSliderValueChanged(app, event)
            value = app.maxGrayValueSlider.Value;
            if (value > 1) || (value < app.minGrayValue)
                msgbox('Invalid operation!');
                app.maxGrayValueSlider.Value = app.maxGrayValue;
            else
                app.maxGrayValue = value;
                app.maxGrayValueEdit.Value = app.maxGrayValue;
                if ~isempty(app.initGrayDiffPatt)                
                    UpdateDispDiffPatt(app);                    
                    UpdateHistogram(app);                
                end
            end
        end

        % Button pushed function: minGrayValueMinusButton
        function minGrayValueMinusButtonPushed(app, event)
            tmpMinGrayValue = app.minGrayValue - app.minGrayValueStep;
            if (tmpMinGrayValue < 0) || (tmpMinGrayValue > app.maxGrayValue)
                msgbox('Invalid operation!');
            else
                app.minGrayValue = tmpMinGrayValue;
                app.minGrayValueEdit.Value = app.minGrayValue;
                app.minGrayValueSlider.Value = app.minGrayValue;
                if ~isempty(app.initGrayDiffPatt)                
                    UpdateDispDiffPatt(app);                    
                    UpdateHistogram(app);                
                end
            end
        end

        % Button pushed function: minGrayValueAddButton
        function minGrayValueAddButtonPushed(app, event)
            tmpMinGrayValue = app.minGrayValue + app.minGrayValueStep;
            if (tmpMinGrayValue < 0) || (tmpMinGrayValue > app.maxGrayValue)
                msgbox('Invalid operation!');
            else
                app.minGrayValue = tmpMinGrayValue;
                app.minGrayValueEdit.Value = app.minGrayValue;
                app.minGrayValueSlider.Value = app.minGrayValue;
                if ~isempty(app.initGrayDiffPatt)                
                    UpdateDispDiffPatt(app);                    
                    UpdateHistogram(app);                
                end
            end
        end

        % Button pushed function: maxGrayValueMinusButton
        function maxGrayValueMinusButtonPushed(app, event)
            tmpMaxGrayValue = app.maxGrayValue - app.maxGrayValueStep;
            if (tmpMaxGrayValue > 1) || (tmpMaxGrayValue < app.minGrayValue)
                msgbox('Invalid operation!');
            else
                app.maxGrayValue = tmpMaxGrayValue;
                app.maxGrayValueEdit.Value = app.maxGrayValue;
                app.maxGrayValueSlider.Value = app.maxGrayValue;
                if ~isempty(app.initGrayDiffPatt)                
                    UpdateDispDiffPatt(app);                    
                    UpdateHistogram(app);                
                end
            end
        end

        % Button pushed function: maxGrayValueAddButton
        function maxGrayValueAddButtonPushed(app, event)
            tmpMaxGrayValue = app.maxGrayValue + app.maxGrayValueStep;
            if (tmpMaxGrayValue > 1) || (tmpMaxGrayValue < app.minGrayValue)
                msgbox('Invalid operation!');
            else
                app.maxGrayValue = tmpMaxGrayValue;
                app.maxGrayValueEdit.Value = app.maxGrayValue;
                app.maxGrayValueSlider.Value = app.maxGrayValue;
                if ~isempty(app.initGrayDiffPatt)                
                    UpdateDispDiffPatt(app);                    
                    UpdateHistogram(app);                
                end
            end
        end

        % Value changed function: minGrayValueStepEdit
        function minGrayValueStepEditValueChanged(app, event)
            value = app.minGrayValueStepEdit.Value;
            if (value < 0) || (value > 1)
                msgbox('Invalid operation!');
                app.minGrayValueStepEdit.Value = app.minGrayValueStep;
            else
                app.minGrayValueStep = value;
            end
        end

        % Value changed function: maxGrayValueStepEdit
        function maxGrayValueStepEditValueChanged(app, event)
            value = app.maxGrayValueStepEdit.Value;
            if (value < 0) || (value > 1)
                msgbox('Invalid operation!');
                app.maxGrayValueStepEdit.Value = app.maxGrayValueStep;
            else
                app.maxGrayValueStep = value;
            end
        end

        % Value changed function: gammaValueStepEdit
        function gammaValueStepEditValueChanged(app, event)
            value = app.gammaValueStepEdit.Value;
            if value < 0
                msgbox('Invalid operation!');
                app.gammaValueStepEdit.Value = app.gammaValueStep;
            else
                app.gammaValueStep = value;
            end
        end

        % Button pushed function: gammaValueMinusButton
        function gammaValueMinusButtonPushed(app, event)
            tmpGammaValue = app.gammaValue - app.gammaValueStep;
            if tmpGammaValue < 0
                msgbox('Invalid operation!');
            else
                app.gammaValue = tmpGammaValue;
                app.gammaValueEdit.Value = app.gammaValue;
                if ~isempty(app.initGrayDiffPatt)
                    UpdateDispDiffPatt(app);
                    UpdateHistogram(app);
                end
            end
        end

        % Button pushed function: gammaValueAddButton
        function gammaValueAddButtonPushed(app, event)
            tmpGammaValue = app.gammaValue + app.gammaValueStep;
            if tmpGammaValue < 0
                msgbox('Invalid operation!');
            else
                app.gammaValue = tmpGammaValue;
                app.gammaValueEdit.Value = app.gammaValue;
                if ~isempty(app.initGrayDiffPatt)
                    UpdateDispDiffPatt(app);
                    UpdateHistogram(app);
                end
            end
        end

        % Value changed function: voltageEdit
        function voltageEditValueChanged(app, event)
            value = app.voltageEdit.Value;
            if value > 0
                app.voltage = value;
                app.wavLen = HighEnergyWavLen_X(app.voltage);
            else
                msgbox('Voltage must have a positive value!');
                app.voltageEdit.Value = app.voltage;
            end
        end

        % Value changed function: showProbePositionButton
        function showProbePositionButtonValueChanged(app, event)
            value = app.showProbePositionButton.Value;
            if ~isempty(app.crystalMatrix)
                if value
                    PlotProbeOnCrystal(app);
                else
                    PlotCrystal(app);
                end
            else
                msgbox('Please load xyz file first!');
                app.showProbePositionButton.Value = 0;
            end
        end

        % Value changed function: probeXEdit
        function probeXEditValueChanged(app, event)
            value = app.probeXEdit.Value;
            if ~isempty(app.crystalMatrix)
                app.probeX = value;
                if app.showProbePositionButton.Value
                    PlotProbeOnCrystal(app);
                end
            else
                msgbox('Please load xyz file first!');
                app.probeXEdit.Value = app.probeX;
            end
        end

        % Value changed function: probeYEdit
        function probeYEditValueChanged(app, event)
            value = app.probeYEdit.Value;
            if ~isempty(app.crystalMatrix)
                app.probeY = value;
                if app.showProbePositionButton.Value
                    PlotProbeOnCrystal(app);
                end
            else
                msgbox('Please load xyz file first!');
                app.probeYEdit.Value = app.probeY;
            end
        end

        % Button pushed function: backToOriginButton
        function backToOriginButtonPushed(app, event)
            app.probeX = 0;
            app.probeXEdit.Value = app.probeX;
            app.probeY = 0;
            app.probeYEdit.Value = app.probeY;
            if app.showProbePositionButton.Value
                PlotProbeOnCrystal(app);
            end
        end

        % Value changed function: probeXyStepEdit
        function probeXyStepEditValueChanged(app, event)
            value = app.probeXyStepEdit.Value;
            if value < 0
                msgbox('Moving step must have a positive value!');
                app.probeXyStepEdit.Value = app.probeXyStep;
            else
                app.probeXyStep = value;
            end
        end

        % Button pushed function: probeUpButton
        function probeUpButtonPushed(app, event)
            if ~isempty(app.crystalMatrix)
                app.probeY = app.probeY + app.probeXyStep;
                app.probeYEdit.Value = app.probeY;
                if app.showProbePositionButton.Value
                    PlotProbeOnCrystal(app);
                end
            else
                msgbox('Please load xyz file first!');
            end
        end

        % Button pushed function: probeDownButton
        function probeDownButtonPushed(app, event)
            if ~isempty(app.crystalMatrix)
                app.probeY = app.probeY - app.probeXyStep;
                app.probeYEdit.Value = app.probeY;
                if app.showProbePositionButton.Value
                    PlotProbeOnCrystal(app);
                end
            else
                msgbox('Please load xyz file first!');
            end
        end

        % Button pushed function: probeLeftButton
        function probeLeftButtonPushed(app, event)
            if ~isempty(app.crystalMatrix)
                app.probeX = app.probeX - app.probeXyStep;
                app.probeXEdit.Value = app.probeX;
                if app.showProbePositionButton.Value
                    PlotProbeOnCrystal(app);
                end
            else
                msgbox('Please load xyz file first!');
            end
        end

        % Button pushed function: probeRightButton
        function probeRightButtonPushed(app, event)
            if ~isempty(app.crystalMatrix)
                app.probeX = app.probeX + app.probeXyStep;
                app.probeXEdit.Value = app.probeX;
                if app.showProbePositionButton.Value
                    PlotProbeOnCrystal(app);
                end
            else
                msgbox('Please load xyz file first!');
            end
        end

        % Value changed function: apertSizeEdit
        function apertSizeEditValueChanged(app, event)
            value = app.apertSizeEdit.Value;
            if value < 0
                msgbox('Aperture size must be a positive value!');
                app.apertSizeEdit.Value = app.apertSize;
            else
                app.apertSize = value;
            end
        end

        % Value changed function: C1Edit
        function C1EditValueChanged(app, event)
            value = app.C1Edit.Value;
            app.C1 = value * LengthUnitToAngstromScale(app.C1UnitDropDown.Value);
        end

        % Value changed function: A1Edit
        function A1EditValueChanged(app, event)
            value = app.A1Edit.Value;
            app.A1 = value * LengthUnitToAngstromScale(app.A1UnitDropDown.Value);
        end

        % Value changed function: A2Edit
        function A2EditValueChanged(app, event)
            value = app.A2Edit.Value;
            app.A2 = value * LengthUnitToAngstromScale(app.A2UnitDropDown.Value);
        end

        % Value changed function: B2Edit
        function B2EditValueChanged(app, event)
            value = app.B2Edit.Value;
            app.B2 = value * LengthUnitToAngstromScale(app.B2UnitDropDown.Value);
        end

        % Value changed function: C3Edit
        function C3EditValueChanged(app, event)
            value = app.C3Edit.Value;
            app.C3 = value * LengthUnitToAngstromScale(app.C3UnitDropDown.Value);
        end

        % Value changed function: A3Edit
        function A3EditValueChanged(app, event)
            value = app.A3Edit.Value;
            app.A3 = value * LengthUnitToAngstromScale(app.A3UnitDropDown.Value);
        end

        % Value changed function: S3Edit
        function S3EditValueChanged(app, event)
            value = app.S3Edit.Value;
            app.S3 = value * LengthUnitToAngstromScale(app.S3UnitDropDown.Value);
        end

        % Value changed function: A4Edit
        function A4EditValueChanged(app, event)
            value = app.A4Edit.Value;
            app.A4 = value * LengthUnitToAngstromScale(app.A4UnitDropDown.Value);
        end

        % Value changed function: A1AngleEdit
        function A1AngleEditValueChanged(app, event)
            value = app.A1AngleEdit.Value;
            app.A1Angle = value;
        end

        % Value changed function: A2AngleEdit
        function A2AngleEditValueChanged(app, event)
            value = app.A2AngleEdit.Value;
            app.A2Angle = value;
        end

        % Value changed function: B2AngleEdit
        function B2AngleEditValueChanged(app, event)
            value = app.B2AngleEdit.Value;
            app.B2Angle = value;
        end

        % Value changed function: A3AngleEdit
        function A3AngleEditValueChanged(app, event)
            value = app.A3AngleEdit.Value;
            app.A3Angle = value;
        end

        % Value changed function: S3AngleEdit
        function S3AngleEditValueChanged(app, event)
            value = app.S3AngleEdit.Value;
            app.S3Angle = value;
        end

        % Value changed function: A4AngleEdit
        function A4AngleEditValueChanged(app, event)
            value = app.A4AngleEdit.Value;
            app.A4Angle = value;
        end

        % Value changed function: C1UnitDropDown
        function C1UnitDropDownValueChanged(app, event)
            value = app.C1UnitDropDown.Value;
            app.C1 = app.C1Edit.Value * LengthUnitToAngstromScale(value);
        end

        % Value changed function: A1UnitDropDown
        function A1UnitDropDownValueChanged(app, event)
            value = app.A1UnitDropDown.Value;
            app.A1 = app.A1Edit.Value * LengthUnitToAngstromScale(value);
        end

        % Value changed function: A2UnitDropDown
        function A2UnitDropDownValueChanged(app, event)
            value = app.A2UnitDropDown.Value;
            app.A2 = app.A2Edit.Value * LengthUnitToAngstromScale(value);
        end

        % Value changed function: B2UnitDropDown
        function B2UnitDropDownValueChanged(app, event)
            value = app.B2UnitDropDown.Value;
            app.B2 = app.B2Edit.Value * LengthUnitToAngstromScale(value);
        end

        % Value changed function: C3UnitDropDown
        function C3UnitDropDownValueChanged(app, event)
            value = app.C3UnitDropDown.Value;
            app.C3 = app.C3Edit.Value * LengthUnitToAngstromScale(value);
        end

        % Value changed function: A3UnitDropDown
        function A3UnitDropDownValueChanged(app, event)
            value = app.A3UnitDropDown.Value;
            app.A3 = app.A3Edit.Value * LengthUnitToAngstromScale(value);
        end

        % Value changed function: S3UnitDropDown
        function S3UnitDropDownValueChanged(app, event)
            value = app.S3UnitDropDown.Value;
            app.S3 = app.S3Edit.Value * LengthUnitToAngstromScale(value);
        end

        % Value changed function: A4UnitDropDown
        function A4UnitDropDownValueChanged(app, event)
            value = app.A4UnitDropDown.Value;
            app.A4 = app.A4Edit.Value * LengthUnitToAngstromScale(value);
        end

        % Button pushed function: generateObjTransFuncButton
        function generateObjTransFuncButtonPushed(app, event)
            CreateObjTransFuncPhase(app);
            
            % show demo objective transfer function phase on app.otfPhaseAxes
            imshow(app.demoObjTransFuncPhase, [], 'Parent', app.otfPhaseAxes);
            app.otfLastUpadateTimeEdit.Value = app.UpdateTime();
            
            % show demo probe on app.probeAxes
            imshow(app.demoProbe, 'Parent', app.probeAxes);
            app.probeLastUpdateTimeEdit.Value = app.UpdateTime();
        end

        % Button pushed function: diffractButton
        function diffractButtonPushed(app, event)
            if (~isempty(app.projPotMat)) && (~isempty(app.sliceDist))
                switch app.diffModeSwitch.Value
                    case 'TEM'
                        SelectedAreaDiffract(app);
                    case 'STEM'
                        if ~isempty(app.objTransFunc)
                            ConvergentBeamDiffract(app);
%                             app.diffPatt = NanoDiffAppCbed(app.voltage, app.objTransFunc,...
%                                 app.probeX, app.probeY, app.projPotMat, app.sliceDist,...
%                                 app.pixelSize, app.dimPixelNum);
                        else
                            msgbox('Please generate OTF first!');
                        end
                    otherwise
                        % do nothing and throw a tip
                        msgbox('Operation failed!');
                end
                
%                 app.initGrayDiffPatt = mat2gray(app.diffPatt);
                
                UpdateDispDiffPatt(app);
                UpdateHistogram(app);
            else
                msgbox('Please generate specimen projected potential!');
            end
        end

        % Menu selected function: unadjDiffPattAsTextMenu
        function unadjDiffPattAsTextMenuSelected(app, event)
            if ~isempty(app.diffPatt)
                [file, path] = uiputfile('untitled.txt');
                if (~isequal(file, 0)) && (~isequal(path, 0))
                    filename = fullfile(path, file);
                    diffPattToSave = app.diffPatt;
                    save(filename, 'diffPattToSave', '-ascii', '-double', '-tabs');
                end
            else
                msgbox('Please generate the diffraction pattern first!');
            end
        end

        % Menu selected function: unadjDiffPattAsTiffMenu
        function unadjDiffPattAsTiffMenuSelected(app, event)
            if ~isempty(app.initGrayDiffPatt)
                [file, path] = uiputfile('untitled.tif');
                if (~isequal(file, 0)) && (~isequal(path, 0))
                    filename = fullfile(path, file);
                    diffPattToSave = app.initGrayDiffPatt;
                    imwrite(diffPattToSave, filename, 'Compression', 'none');
                end
            else
                msgbox('Please generate the diffraction pattern first!');
            end
        end

        % Menu selected function: adjDiffPattAsTiffMenu
        function adjDiffPattAsTiffMenuSelected(app, event)
            if ~isempty(app.adjGrayDiffPatt)
                [file, path] = uiputfile('untitled.tif');
                if (~isequal(file, 0)) && (~isequal(path, 0))
                    filename = fullfile(path, file);
                    diffPattToSave = app.adjGrayDiffPatt;
                    imwrite(diffPattToSave, filename, 'Compression', 'none');
                end
            else
                msgbox('Please generate the diffraction pattern first!');
            end
        end

        % Menu selected function: unadjDiffPattAsJpegMenu
        function unadjDiffPattAsJpegMenuSelected(app, event)
            if ~isempty(app.initGrayDiffPatt)
                [file, path] = uiputfile('untitled.jpg');
                if (~isequal(file, 0)) && (~isequal(path, 0))
                    filename = fullfile(path, file);
                    diffPattToSave = app.initGrayDiffPatt;
                    imwrite(diffPattToSave, filename);
                end
            else
                msgbox('Please generate the diffraction pattern first!');
            end
        end

        % Menu selected function: adjDiffPattAsJpegMenu
        function adjDiffPattAsJpegMenuSelected(app, event)
            if ~isempty(app.adjGrayDiffPatt)
                [file, path] = uiputfile('untitled.jpg');
                if (~isequal(file, 0)) && (~isequal(path, 0))
                    filename = fullfile(path, file);
                    diffPattToSave = app.adjGrayDiffPatt;
                    imwrite(diffPattToSave, filename);
                end
            else
                msgbox('Please generate the diffraction pattern first!');
            end
        end

        % Menu selected function: otfPhaseAsTextMenu
        function otfPhaseAsTextMenuSelected(app, event)
            if ~isempty(app.demoObjTransFuncPhase)
                [file, path] = uiputfile('untitled.txt');
                if (~isequal(file, 0)) && (~isequal(path, 0))
                    filename = fullfile(path, file);
                    otfPhaseImg = app.demoObjTransFuncPhase;
                    save(filename, 'otfPhaseImg', '-ascii', '-double', '-tabs');
                end
            else
                msgbox('Please generate OTF phase first!');
            end
        end

        % Menu selected function: otfPhaseAsTiffMenu
        function otfPhaseAsTiffMenuSelected(app, event)
            if ~isempty(app.demoObjTransFuncPhase)
                [file, path] = uiputfile('untitled.tif');
                if (~isequal(file, 0)) && (~isequal(path, 0))
                    filename = fullfile(path, file);
                    otfPhaseImg = mat2gray(app.demoObjTransFuncPhase);
                    imwrite(otfPhaseImg, filename, 'Compression', 'none');
                end
            else
                msgbox('Please generate OTF phase first!');
            end
        end

        % Menu selected function: otfPhaseAsJpegMenu
        function otfPhaseAsJpegMenuSelected(app, event)
            if ~isempty(app.demoObjTransFuncPhase)
                [file, path] = uiputfile('untitled.jpg');
                if (~isequal(file, 0)) && (~isequal(path, 0))
                    filename = fullfile(path, file);
                    otfPhaseImg = mat2gray(app.demoObjTransFuncPhase);
                    imwrite(otfPhaseImg, filename);
                end
            else
                msgbox('Please generate OTF phase first!');
            end
        end

        % Menu selected function: editElePropMenu
        function editElePropMenuSelected(app, event)
            app.editElePropMenu.Enable = 'off';
            
            % prepare input argument for ElePropDialogApp: NanoDiffEditEleProp
            externCrysMat = app.crystalMatrix;
            
            % call ElePropDialogApp: NanoDiffEditEleProp
            app.ElePropDialogApp = NanoDiffEditEleProp(app, externCrysMat);
        end

        % Close request function: NanoDiffUIFigure
        function NanoDiffUIFigureCloseRequest(app, event)
            % Delete the app and its dialog apps
            delete(app.ElePropDialogApp);
            delete(app)
        end

        % Menu selected function: removeSpecimenMenu
        function removeSpecimenMenuSelected(app, event)
            answer = questdlg('Are you sure to remove the current specimen?',...
                'Remove current specimen');
            
            % process the answer
            switch answer
                case 'Yes'
                    % clear
                    RemoveSpecimen(app);
                otherwise
                    % do nothing
            end
        end

        % Value changed function: polarEdit
        function polarEditValueChanged(app, event)
            value = app.polarEdit.Value;
            if value < 0
                app.polarEdit.Value = app.polarTiltAngle;
                msgbox('Polar angle must have positive value!');
            else
                app.polarTiltAngle = value;
                if strcmp(app.diffModeSwitch.Value, 'TEM')
                    PlotTiltBeam(app);
                end
            end
        end

        % Value changed function: azimEdit
        function azimEditValueChanged(app, event)
            value = app.azimEdit.Value;
            app.azimTiltAngle = value;
            if strcmp(app.diffModeSwitch.Value, 'TEM')
                PlotTiltBeam(app);
            end
        end

        % Value changed function: diffModeSwitch
        function diffModeSwitchValueChanged(app, event)
            value = app.diffModeSwitch.Value;
            switch value
                case 'TEM'
                    app.temDiffLamp.Color = [0 1 0];
                    app.stemDiffLamp.Color = [1 0 0];
                    
                    PlotTiltBeam(app);
                case 'STEM'
                    app.temDiffLamp.Color = [1 0 0];
                    app.stemDiffLamp.Color = [0 1 0];
                    
                    cla(app.probeAxes);
                otherwise
                    % do nothing
            end
        end

        % Value changed function: polarStepEdit
        function polarStepEditValueChanged(app, event)
            value = app.polarStepEdit.Value;
            if value > 0
                app.polarTiltStep = value;
            else
                app.polarStepEdit.Value = app.polarTiltStep;
                msgbox('Step must have positive value!');
            end
        end

        % Value changed function: azimStepEdit
        function azimStepEditValueChanged(app, event)
            value = app.azimStepEdit.Value;
            if value > 0
                app.azimTiltStep = value;
            else
                app.azimStepEdit.Value = app.azimTiltStep;
                msgbox('Step must have positive value!');
            end
        end

        % Button pushed function: azimClockButton
        function azimClockButtonPushed(app, event)
            app.azimTiltAngle = app.azimTiltAngle - app.azimTiltStep;
            app.azimEdit.Value = app.azimTiltAngle;
            
            if strcmp(app.diffModeSwitch.Value, 'TEM')
                PlotTiltBeam(app);
            end
        end

        % Button pushed function: azimAntiClockButton
        function azimAntiClockButtonPushed(app, event)
            app.azimTiltAngle = app.azimTiltAngle + app.azimTiltStep;
            app.azimEdit.Value = app.azimTiltAngle;
            
            if strcmp(app.diffModeSwitch.Value, 'TEM')
                PlotTiltBeam(app);
            end
        end

        % Button pushed function: polarPlusButton
        function polarPlusButtonPushed(app, event)
            value = app.polarTiltAngle + app.polarTiltStep;
            if value > 0
                app.polarTiltAngle = value;
                app.polarEdit.Value = app.polarTiltAngle;
                
                if strcmp(app.diffModeSwitch.Value, 'TEM')
                    PlotTiltBeam(app);
                end
            end
        end

        % Button pushed function: polarMinusButton
        function polarMinusButtonPushed(app, event)
            value = app.polarTiltAngle - app.polarTiltStep;
            if value > 0
                app.polarTiltAngle = value;
                app.polarEdit.Value = app.polarTiltAngle;
                
                if strcmp(app.diffModeSwitch.Value, 'TEM')
                    PlotTiltBeam(app);
                end
            end
        end

        % Value changed function: addCirleButton
        function addCirleButtonValueChanged(app, event)
            value = app.addCirleButton.Value;
            if ~isempty(app.initGrayDiffPatt)
                UpdateDispDiffPatt(app);
            else
                app.addCirleButton.Value = ~value;
            end
        end

        % Value changed function: circleRadiusEdit
        function circleRadiusEditValueChanged(app, event)
            if ~isempty(app.initGrayDiffPatt)
                UpdateDispDiffPatt(app);
            end
        end

        % Menu selected function: LoadCIFMenu
        function LoadCIFMenuSelected(app, event)
            [file, path] = uigetfile('*.cif');
            if ~(isequal(file, 0) || isequal(path, 0))
                app.fileType = 'cif';
                app.unitCellAtomSiteMat = [];
                app.atomSiteMat = [];
                
                crysInfo = LoadCif(fullfile(path, file));
                
                [app.cellLengths, app.cellAngles] = ExtractCellConstFromCrysInfo(crysInfo);
                DispCellInfo(app);
                
                app.unitCellAtomSiteMat = ExtractAtomSiteFromCrysInfo(crysInfo);
                app.atomSiteMat = AddEquivAtomSites(app.unitCellAtomSiteMat);
                app.atomNumEdit.Value = size(app.atomSiteMat, 2);
                
                SortAtomSiteMatByType(app);
                
                hkl = [0, 0, 1];
                app.convMat = ConversionMatrix_hkl(app.cellLengths, app.cellAngles, hkl);
                
                app.crystalMatrix = app.atomSiteMat;
                app.crystalMatrix(3 : 5, :) = app.convMat * app.crystalMatrix(3 : 5, :);
                
                PlotCrystalFitScreen(app);
            end
        end

        % Menu selected function: RectangleMenu
        function RectangleMenuSelected(app, event)
            % Disable this menu while the dialog app is open
            app.RectangleMenu.Enable = 'off';
            
            % Call the dialog app
            app.RectSampleDialogApp = RectSampleSize(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NanoDiffUIFigure = uifigure('Visible', 'off');
            app.NanoDiffUIFigure.Name = 'NanoDiff';
            app.NanoDiffUIFigure.CloseRequestFcn = createCallbackFcn(app, @NanoDiffUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.NanoDiffUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadxyzfileMenu = uimenu(app.FileMenu);
            app.LoadxyzfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadxyzfileMenuSelected, true);
            app.LoadxyzfileMenu.Text = 'Load xyz file';
            app.LoadCIFMenu = uimenu(app.FileMenu);
            app.LoadCIFMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadCIFMenuSelected, true);
            app.LoadCIFMenu.Text = 'Load CIF';
            app.saveUnadjDiffPattMenu = uimenu(app.FileMenu);
            app.saveUnadjDiffPattMenu.Text = 'Save unadjusted diffraction pattern';
            app.unadjDiffPattAsTextMenu = uimenu(app.saveUnadjDiffPattMenu);
            app.unadjDiffPattAsTextMenu.MenuSelectedFcn = createCallbackFcn(app, @unadjDiffPattAsTextMenuSelected, true);
            app.unadjDiffPattAsTextMenu.Text = 'Text image';
            app.unadjDiffPattAsTiffMenu = uimenu(app.saveUnadjDiffPattMenu);
            app.unadjDiffPattAsTiffMenu.MenuSelectedFcn = createCallbackFcn(app, @unadjDiffPattAsTiffMenuSelected, true);
            app.unadjDiffPattAsTiffMenu.Text = 'TIFF';
            app.unadjDiffPattAsJpegMenu = uimenu(app.saveUnadjDiffPattMenu);
            app.unadjDiffPattAsJpegMenu.MenuSelectedFcn = createCallbackFcn(app, @unadjDiffPattAsJpegMenuSelected, true);
            app.unadjDiffPattAsJpegMenu.Text = 'JPEG';
            app.SaveajusteddiffractionpatternMenu = uimenu(app.FileMenu);
            app.SaveajusteddiffractionpatternMenu.Text = 'Save ajusted diffraction pattern';
            app.adjDiffPattAsTiffMenu = uimenu(app.SaveajusteddiffractionpatternMenu);
            app.adjDiffPattAsTiffMenu.MenuSelectedFcn = createCallbackFcn(app, @adjDiffPattAsTiffMenuSelected, true);
            app.adjDiffPattAsTiffMenu.Text = 'TIFF';
            app.adjDiffPattAsJpegMenu = uimenu(app.SaveajusteddiffractionpatternMenu);
            app.adjDiffPattAsJpegMenu.MenuSelectedFcn = createCallbackFcn(app, @adjDiffPattAsJpegMenuSelected, true);
            app.adjDiffPattAsJpegMenu.Text = 'JPEG';
            app.saveOtfPhaseMenu = uimenu(app.FileMenu);
            app.saveOtfPhaseMenu.Text = 'Save OTF phase';
            app.otfPhaseAsTextMenu = uimenu(app.saveOtfPhaseMenu);
            app.otfPhaseAsTextMenu.MenuSelectedFcn = createCallbackFcn(app, @otfPhaseAsTextMenuSelected, true);
            app.otfPhaseAsTextMenu.Text = 'Text image';
            app.otfPhaseAsTiffMenu = uimenu(app.saveOtfPhaseMenu);
            app.otfPhaseAsTiffMenu.MenuSelectedFcn = createCallbackFcn(app, @otfPhaseAsTiffMenuSelected, true);
            app.otfPhaseAsTiffMenu.Text = 'TIFF';
            app.otfPhaseAsJpegMenu = uimenu(app.saveOtfPhaseMenu);
            app.otfPhaseAsJpegMenu.MenuSelectedFcn = createCallbackFcn(app, @otfPhaseAsJpegMenuSelected, true);
            app.otfPhaseAsJpegMenu.Text = 'JPEG';
            app.removeSpecimenMenu = uimenu(app.FileMenu);
            app.removeSpecimenMenu.MenuSelectedFcn = createCallbackFcn(app, @removeSpecimenMenuSelected, true);
            app.removeSpecimenMenu.Text = 'Remove current specimen';
            app.EditMenu = uimenu(app.NanoDiffUIFigure);
            app.EditMenu.Text = 'Edit';
            app.editElePropMenu = uimenu(app.EditMenu);
            app.editElePropMenu.MenuSelectedFcn = createCallbackFcn(app, @editElePropMenuSelected, true);
            app.editElePropMenu.Text = 'Edit elemental proportion';
            app.BuildMenu = uimenu(app.NanoDiffUIFigure);
            app.BuildMenu.Text = 'Build';
            app.RectangleMenu = uimenu(app.BuildMenu);
            app.RectangleMenu.MenuSelectedFcn = createCallbackFcn(app, @RectangleMenuSelected, true);
            app.RectangleMenu.Text = 'Rectangle';
            app.NanoclusterMenu = uimenu(app.BuildMenu);
            app.NanoclusterMenu.Text = 'Nano-cluster';
            app.TabGroup = uitabgroup(app.NanoDiffUIFigure);
            app.diffractionTab = uitab(app.TabGroup);
            app.diffractionTab.Title = 'Diffraction';
            app.VoltagekVEditFieldLabel = uilabel(app.diffractionTab);
            app.VoltagekVEditFieldLabel.Text = 'Voltage (kV)';
            app.voltageEdit = uieditfield(app.diffractionTab, 'numeric');
            app.voltageEdit.ValueChangedFcn = createCallbackFcn(app, @voltageEditValueChanged, true);
            app.diffractButton = uibutton(app.diffractionTab, 'push');
            app.diffractButton.ButtonPushedFcn = createCallbackFcn(app, @diffractButtonPushed, true);
            app.diffractButton.Text = 'Diffract';
            app.ProbeUpdateTimeLabel = uilabel(app.diffractionTab);
            app.ProbeUpdateTimeLabel.Text = 'Probe Update Time';
            app.probeLastUpdateTimeEdit = uieditfield(app.diffractionTab, 'text');
            app.stemPanel = uipanel(app.diffractionTab);
            app.stemPanel.Title = 'STEM';
            app.AperturesizemradEditFieldLabel = uilabel(app.stemPanel);
            app.AperturesizemradEditFieldLabel.Text = 'Aperture size (mrad)';
            app.apertSizeEdit = uieditfield(app.stemPanel, 'numeric');
            app.apertSizeEdit.ValueChangedFcn = createCallbackFcn(app, @apertSizeEditValueChanged, true);
            app.probeCenterLabel = uilabel(app.stemPanel);
            app.probeCenterLabel.Text = 'Probe center (Angs.)';
            app.MovingstepLabel = uilabel(app.stemPanel);
            app.MovingstepLabel.Text = 'Moving step';
            app.probeXyStepEdit = uieditfield(app.stemPanel, 'numeric');
            app.probeXyStepEdit.ValueChangedFcn = createCallbackFcn(app, @probeXyStepEditValueChanged, true);
            app.xEditFieldLabel = uilabel(app.stemPanel);
            app.xEditFieldLabel.Text = 'x';
            app.probeXEdit = uieditfield(app.stemPanel, 'numeric');
            app.probeXEdit.ValueChangedFcn = createCallbackFcn(app, @probeXEditValueChanged, true);
            app.yEditFieldLabel = uilabel(app.stemPanel);
            app.yEditFieldLabel.Text = 'y';
            app.probeYEdit = uieditfield(app.stemPanel, 'numeric');
            app.probeYEdit.ValueChangedFcn = createCallbackFcn(app, @probeYEditValueChanged, true);
            app.probeLeftButton = uibutton(app.stemPanel, 'push');
            app.probeLeftButton.ButtonPushedFcn = createCallbackFcn(app, @probeLeftButtonPushed, true);
            app.probeLeftButton.Icon = 'leftArrow-1.jpg';
            app.probeLeftButton.Text = '';
            app.probeUpButton = uibutton(app.stemPanel, 'push');
            app.probeUpButton.ButtonPushedFcn = createCallbackFcn(app, @probeUpButtonPushed, true);
            app.probeUpButton.Icon = 'upArrow-1.jpg';
            app.probeUpButton.Text = '';
            app.probeRightButton = uibutton(app.stemPanel, 'push');
            app.probeRightButton.ButtonPushedFcn = createCallbackFcn(app, @probeRightButtonPushed, true);
            app.probeRightButton.Icon = 'rightArrow-1.jpg';
            app.probeRightButton.Text = '';
            app.probeDownButton = uibutton(app.stemPanel, 'push');
            app.probeDownButton.ButtonPushedFcn = createCallbackFcn(app, @probeDownButtonPushed, true);
            app.probeDownButton.Icon = 'downArrow-1.jpg';
            app.probeDownButton.Text = '';
            app.backToOriginButton = uibutton(app.stemPanel, 'push');
            app.backToOriginButton.ButtonPushedFcn = createCallbackFcn(app, @backToOriginButtonPushed, true);
            app.backToOriginButton.Text = 'Back to origin';
            app.showProbePositionButton = uibutton(app.stemPanel, 'state');
            app.showProbePositionButton.ValueChangedFcn = createCallbackFcn(app, @showProbePositionButtonValueChanged, true);
            app.showProbePositionButton.Text = {'Show probe'; 'position'};
            app.stemDiffLamp = uilamp(app.stemPanel);
            app.ModeSwitchLabel = uilabel(app.diffractionTab);
            app.ModeSwitchLabel.Text = 'Mode';
            app.diffModeSwitch = uiswitch(app.diffractionTab, 'slider');
            app.diffModeSwitch.Items = {'TEM', 'STEM'};
            app.diffModeSwitch.ValueChangedFcn = createCallbackFcn(app, @diffModeSwitchValueChanged, true);
            app.diffModeSwitch.Value = 'TEM';
            app.temPanel = uipanel(app.diffractionTab);
            app.temPanel.Title = 'TEM';
            app.temDiffLamp = uilamp(app.temPanel);
            app.PolarEditFieldLabel = uilabel(app.temPanel);
            app.PolarEditFieldLabel.Text = 'Polar';
            app.polarEdit = uieditfield(app.temPanel, 'numeric');
            app.polarEdit.ValueChangedFcn = createCallbackFcn(app, @polarEditValueChanged, true);
            app.AzimuthalEditFieldLabel = uilabel(app.temPanel);
            app.AzimuthalEditFieldLabel.Text = 'Azimuthal';
            app.azimEdit = uieditfield(app.temPanel, 'numeric');
            app.azimEdit.ValueChangedFcn = createCallbackFcn(app, @azimEditValueChanged, true);
            app.beamtiltLabel = uilabel(app.temPanel);
            app.beamtiltLabel.Text = 'Beam tilt';
            app.StepEditFieldLabel_4 = uilabel(app.temPanel);
            app.StepEditFieldLabel_4.Text = 'Step';
            app.polarStepEdit = uieditfield(app.temPanel, 'numeric');
            app.polarStepEdit.ValueChangedFcn = createCallbackFcn(app, @polarStepEditValueChanged, true);
            app.StepLabel = uilabel(app.temPanel);
            app.StepLabel.Text = 'Step';
            app.azimStepEdit = uieditfield(app.temPanel, 'numeric');
            app.azimStepEdit.ValueChangedFcn = createCallbackFcn(app, @azimStepEditValueChanged, true);
            app.polarMinusButton = uibutton(app.temPanel, 'push');
            app.polarMinusButton.ButtonPushedFcn = createCallbackFcn(app, @polarMinusButtonPushed, true);
            app.polarMinusButton.Text = '-';
            app.polarPlusButton = uibutton(app.temPanel, 'push');
            app.polarPlusButton.ButtonPushedFcn = createCallbackFcn(app, @polarPlusButtonPushed, true);
            app.polarPlusButton.Text = '+';
            app.azimAntiClockButton = uibutton(app.temPanel, 'push');
            app.azimAntiClockButton.ButtonPushedFcn = createCallbackFcn(app, @azimAntiClockButtonPushed, true);
            app.azimAntiClockButton.Icon = 'antiClockwiseArrow-1.jpg';
            app.azimAntiClockButton.Text = '';
            app.azimClockButton = uibutton(app.temPanel, 'push');
            app.azimClockButton.ButtonPushedFcn = createCallbackFcn(app, @azimClockButtonPushed, true);
            app.azimClockButton.Icon = 'clockwiseArrow-1.jpg';
            app.azimClockButton.Text = '';
            app.polarUnitLabel = uilabel(app.temPanel);
            app.polarUnitLabel.Text = 'mrad';
            app.azimUnitLabel = uilabel(app.temPanel);
            app.azimUnitLabel.Text = 'degree';
            app.probeAxes = uiaxes(app.diffractionTab);
            title(app.probeAxes, 'Beam / Probe Preview')
            app.probeAxes.XTick = [];
            app.probeAxes.YTick = [];
            app.aberrationTab = uitab(app.TabGroup);
            app.aberrationTab.Title = 'Aberration';
            app.aberrValueLabel = uilabel(app.aberrationTab);
            app.aberrValueLabel.Text = 'Value';
            app.aberrAngleLabel = uilabel(app.aberrationTab);
            app.aberrAngleLabel.Text = 'Angle (degree)';
            app.C1Label = uilabel(app.aberrationTab);
            app.C1Label.Text = 'C1';
            app.A1Label = uilabel(app.aberrationTab);
            app.A1Label.Text = 'A1';
            app.A2Label = uilabel(app.aberrationTab);
            app.A2Label.Text = 'A2';
            app.B2Label = uilabel(app.aberrationTab);
            app.B2Label.Text = 'B2';
            app.C3Label = uilabel(app.aberrationTab);
            app.C3Label.Text = 'C3';
            app.A3Label = uilabel(app.aberrationTab);
            app.A3Label.Text = 'A3';
            app.S3Label = uilabel(app.aberrationTab);
            app.S3Label.Text = 'S3';
            app.A4Label = uilabel(app.aberrationTab);
            app.A4Label.Text = 'A4';
            app.C1Edit = uieditfield(app.aberrationTab, 'numeric');
            app.C1Edit.ValueChangedFcn = createCallbackFcn(app, @C1EditValueChanged, true);
            app.A1Edit = uieditfield(app.aberrationTab, 'numeric');
            app.A1Edit.ValueChangedFcn = createCallbackFcn(app, @A1EditValueChanged, true);
            app.A2Edit = uieditfield(app.aberrationTab, 'numeric');
            app.A2Edit.ValueChangedFcn = createCallbackFcn(app, @A2EditValueChanged, true);
            app.B2Edit = uieditfield(app.aberrationTab, 'numeric');
            app.B2Edit.ValueChangedFcn = createCallbackFcn(app, @B2EditValueChanged, true);
            app.C3Edit = uieditfield(app.aberrationTab, 'numeric');
            app.C3Edit.ValueChangedFcn = createCallbackFcn(app, @C3EditValueChanged, true);
            app.A3Edit = uieditfield(app.aberrationTab, 'numeric');
            app.A3Edit.ValueChangedFcn = createCallbackFcn(app, @A3EditValueChanged, true);
            app.S3Edit = uieditfield(app.aberrationTab, 'numeric');
            app.S3Edit.ValueChangedFcn = createCallbackFcn(app, @S3EditValueChanged, true);
            app.A4Edit = uieditfield(app.aberrationTab, 'numeric');
            app.A4Edit.ValueChangedFcn = createCallbackFcn(app, @A4EditValueChanged, true);
            app.C1UnitDropDown = uidropdown(app.aberrationTab);
            app.C1UnitDropDown.Items = {'mm', 'um', 'nm', 'pm'};
            app.C1UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @C1UnitDropDownValueChanged, true);
            app.C1UnitDropDown.Value = 'mm';
            app.A1UnitDropDown = uidropdown(app.aberrationTab);
            app.A1UnitDropDown.Items = {'mm', 'um', 'nm', 'pm'};
            app.A1UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @A1UnitDropDownValueChanged, true);
            app.A1UnitDropDown.Value = 'mm';
            app.A2UnitDropDown = uidropdown(app.aberrationTab);
            app.A2UnitDropDown.Items = {'mm', 'um', 'nm', 'pm'};
            app.A2UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @A2UnitDropDownValueChanged, true);
            app.A2UnitDropDown.Value = 'mm';
            app.B2UnitDropDown = uidropdown(app.aberrationTab);
            app.B2UnitDropDown.Items = {'mm', 'um', 'nm', 'pm'};
            app.B2UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @B2UnitDropDownValueChanged, true);
            app.B2UnitDropDown.Value = 'mm';
            app.C3UnitDropDown = uidropdown(app.aberrationTab);
            app.C3UnitDropDown.Items = {'mm', 'um', 'nm', 'pm'};
            app.C3UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @C3UnitDropDownValueChanged, true);
            app.C3UnitDropDown.Value = 'mm';
            app.A3UnitDropDown = uidropdown(app.aberrationTab);
            app.A3UnitDropDown.Items = {'mm', 'um', 'nm', 'pm'};
            app.A3UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @A3UnitDropDownValueChanged, true);
            app.A3UnitDropDown.Value = 'mm';
            app.S3UnitDropDown = uidropdown(app.aberrationTab);
            app.S3UnitDropDown.Items = {'mm', 'um', 'nm', 'pm'};
            app.S3UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @S3UnitDropDownValueChanged, true);
            app.S3UnitDropDown.Value = 'mm';
            app.A4UnitDropDown = uidropdown(app.aberrationTab);
            app.A4UnitDropDown.Items = {'mm', 'um', 'nm', 'pm'};
            app.A4UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @A4UnitDropDownValueChanged, true);
            app.A4UnitDropDown.Value = 'mm';
            app.A1AngleEdit = uieditfield(app.aberrationTab, 'numeric');
            app.A1AngleEdit.ValueChangedFcn = createCallbackFcn(app, @A1AngleEditValueChanged, true);
            app.A2AngleEdit = uieditfield(app.aberrationTab, 'numeric');
            app.A2AngleEdit.ValueChangedFcn = createCallbackFcn(app, @A2AngleEditValueChanged, true);
            app.B2AngleEdit = uieditfield(app.aberrationTab, 'numeric');
            app.B2AngleEdit.ValueChangedFcn = createCallbackFcn(app, @B2AngleEditValueChanged, true);
            app.A3AngleEdit = uieditfield(app.aberrationTab, 'numeric');
            app.A3AngleEdit.ValueChangedFcn = createCallbackFcn(app, @A3AngleEditValueChanged, true);
            app.S3AngleEdit = uieditfield(app.aberrationTab, 'numeric');
            app.S3AngleEdit.ValueChangedFcn = createCallbackFcn(app, @S3AngleEditValueChanged, true);
            app.A4AngleEdit = uieditfield(app.aberrationTab, 'numeric');
            app.A4AngleEdit.ValueChangedFcn = createCallbackFcn(app, @A4AngleEditValueChanged, true);
            app.C1AngleLabel = uilabel(app.aberrationTab);
            app.C1AngleLabel.Text = '*';
            app.C3AngleLabel = uilabel(app.aberrationTab);
            app.C3AngleLabel.Text = '*';
            app.generateObjTransFuncButton = uibutton(app.aberrationTab, 'push');
            app.generateObjTransFuncButton.ButtonPushedFcn = createCallbackFcn(app, @generateObjTransFuncButtonPushed, true);
            app.generateObjTransFuncButton.Text = {'Generate objective'; ' transfer function (OTF)'};
            app.OTFUpdateTimeLabel = uilabel(app.aberrationTab);
            app.OTFUpdateTimeLabel.Text = 'OTF Update Time';
            app.otfLastUpadateTimeEdit = uieditfield(app.aberrationTab, 'text');
            app.otfPhaseAxes = uiaxes(app.aberrationTab);
            title(app.otfPhaseAxes, 'OTF Phase')
            app.otfPhaseAxes.XTick = [];
            app.otfPhaseAxes.YTick = [];
            app.diffImagePanel = uipanel(app.NanoDiffUIFigure);
            app.diffImagePanel.Title = 'Diffraction';
            app.MinimumEditFieldLabel = uilabel(app.diffImagePanel);
            app.MinimumEditFieldLabel.Text = 'Minimum';
            app.minGrayValueEdit = uieditfield(app.diffImagePanel, 'numeric');
            app.minGrayValueEdit.ValueChangedFcn = createCallbackFcn(app, @minGrayValueEditValueChanged, true);
            app.StepEditFieldLabel = uilabel(app.diffImagePanel);
            app.StepEditFieldLabel.Text = 'Step';
            app.minGrayValueStepEdit = uieditfield(app.diffImagePanel, 'numeric');
            app.minGrayValueStepEdit.ValueChangedFcn = createCallbackFcn(app, @minGrayValueStepEditValueChanged, true);
            app.minGrayValueMinusButton = uibutton(app.diffImagePanel, 'push');
            app.minGrayValueMinusButton.ButtonPushedFcn = createCallbackFcn(app, @minGrayValueMinusButtonPushed, true);
            app.minGrayValueMinusButton.Text = '-';
            app.minGrayValueAddButton = uibutton(app.diffImagePanel, 'push');
            app.minGrayValueAddButton.ButtonPushedFcn = createCallbackFcn(app, @minGrayValueAddButtonPushed, true);
            app.minGrayValueAddButton.Text = '+';
            app.Label = uilabel(app.diffImagePanel);
            app.Label.Text = '';
            app.minGrayValueSlider = uislider(app.diffImagePanel);
            app.minGrayValueSlider.Limits = [0 1];
            app.minGrayValueSlider.ValueChangedFcn = createCallbackFcn(app, @minGrayValueSliderValueChanged, true);
            app.MaximumEditFieldLabel = uilabel(app.diffImagePanel);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.maxGrayValueEdit = uieditfield(app.diffImagePanel, 'numeric');
            app.maxGrayValueEdit.ValueChangedFcn = createCallbackFcn(app, @maxGrayValueEditValueChanged, true);
            app.StepEditFieldLabel_2 = uilabel(app.diffImagePanel);
            app.StepEditFieldLabel_2.Text = 'Step';
            app.maxGrayValueStepEdit = uieditfield(app.diffImagePanel, 'numeric');
            app.maxGrayValueStepEdit.ValueChangedFcn = createCallbackFcn(app, @maxGrayValueStepEditValueChanged, true);
            app.maxGrayValueMinusButton = uibutton(app.diffImagePanel, 'push');
            app.maxGrayValueMinusButton.ButtonPushedFcn = createCallbackFcn(app, @maxGrayValueMinusButtonPushed, true);
            app.maxGrayValueMinusButton.Text = '-';
            app.maxGrayValueAddButton = uibutton(app.diffImagePanel, 'push');
            app.maxGrayValueAddButton.ButtonPushedFcn = createCallbackFcn(app, @maxGrayValueAddButtonPushed, true);
            app.maxGrayValueAddButton.Text = '+';
            app.Label_2 = uilabel(app.diffImagePanel);
            app.Label_2.Text = '';
            app.maxGrayValueSlider = uislider(app.diffImagePanel);
            app.maxGrayValueSlider.Limits = [0 1];
            app.maxGrayValueSlider.ValueChangedFcn = createCallbackFcn(app, @maxGrayValueSliderValueChanged, true);
            app.GammaEditFieldLabel = uilabel(app.diffImagePanel);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.gammaValueEdit = uieditfield(app.diffImagePanel, 'numeric');
            app.gammaValueEdit.ValueChangedFcn = createCallbackFcn(app, @gammaValueEditValueChanged, true);
            app.gammaValueMinusButton = uibutton(app.diffImagePanel, 'push');
            app.gammaValueMinusButton.ButtonPushedFcn = createCallbackFcn(app, @gammaValueMinusButtonPushed, true);
            app.gammaValueMinusButton.Text = '-';
            app.gammaValueAddButton = uibutton(app.diffImagePanel, 'push');
            app.gammaValueAddButton.ButtonPushedFcn = createCallbackFcn(app, @gammaValueAddButtonPushed, true);
            app.gammaValueAddButton.Text = '+';
            app.StepEditFieldLabel_3 = uilabel(app.diffImagePanel);
            app.StepEditFieldLabel_3.Text = 'Step';
            app.gammaValueStepEdit = uieditfield(app.diffImagePanel, 'numeric');
            app.gammaValueStepEdit.ValueChangedFcn = createCallbackFcn(app, @gammaValueStepEditValueChanged, true);
            app.addCirleButton = uibutton(app.diffImagePanel, 'state');
            app.addCirleButton.ValueChangedFcn = createCallbackFcn(app, @addCirleButtonValueChanged, true);
            app.addCirleButton.Text = 'Add Cirle';
            app.CircleradiusmradEditFieldLabel = uilabel(app.diffImagePanel);
            app.CircleradiusmradEditFieldLabel.Text = 'Circle radius (mrad)';
            app.circleRadiusEdit = uieditfield(app.diffImagePanel, 'numeric');
            app.circleRadiusEdit.Limits = [0 Inf];
            app.circleRadiusEdit.ValueChangedFcn = createCallbackFcn(app, @circleRadiusEditValueChanged, true);
            app.diffractionAxes = uiaxes(app.diffImagePanel);
            app.diffractionAxes.XTick = [];
            app.diffractionAxes.YTick = [];
            app.histogramAxes = uiaxes(app.diffImagePanel);
            title(app.histogramAxes, 'Histogram')
            xlabel(app.histogramAxes, 'Gray value (normalized)')
            ylabel(app.histogramAxes, 'Counts (normalized)')
            app.SpecimenPanel = uipanel(app.NanoDiffUIFigure);
            app.SpecimenPanel.Title = 'Specimen';
            app.AtomNumberEditFieldLabel = uilabel(app.SpecimenPanel);
            app.AtomNumberEditFieldLabel.Text = 'Atom Number';
            app.atomNumEdit = uieditfield(app.SpecimenPanel, 'numeric');
            app.UnitcellconstantsTextAreaLabel = uilabel(app.SpecimenPanel);
            app.UnitcellconstantsTextAreaLabel.Text = 'Unit cell constants';
            app.unitCellConstTextArea = uitextarea(app.SpecimenPanel);
            app.atomSiteAxes = uiaxes(app.SpecimenPanel);
            title(app.atomSiteAxes, 'Atomic sites: x-y view')
            app.OperationSamplingPanel = uipanel(app.NanoDiffUIFigure);
            app.OperationSamplingPanel.Title = 'Operation & Sampling';
            app.TiltStepdegreeEditFieldLabel = uilabel(app.OperationSamplingPanel);
            app.TiltStepdegreeEditFieldLabel.Text = 'Tilt Step (degree)';
            app.outPlaneTiltStepEdit = uieditfield(app.OperationSamplingPanel, 'numeric');
            app.outPlaneTiltStepEdit.ValueChangedFcn = createCallbackFcn(app, @outPlaneTiltStepEditValueChanged, true);
            app.TiltStepdegreeEditFieldLabel_2 = uilabel(app.OperationSamplingPanel);
            app.TiltStepdegreeEditFieldLabel_2.Text = 'Tilt Step (degree)';
            app.inPlaneTiltStepEdit = uieditfield(app.OperationSamplingPanel, 'numeric');
            app.inPlaneTiltStepEdit.ValueChangedFcn = createCallbackFcn(app, @inPlaneTiltStepEditValueChanged, true);
            app.AngleEditFieldLabel = uilabel(app.OperationSamplingPanel);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.upDownTiltAngleEdit = uieditfield(app.OperationSamplingPanel, 'numeric');
            app.upDownTiltAngleEdit.ValueChangedFcn = createCallbackFcn(app, @upDownTiltAngleEditValueChanged, true);
            app.downButton = uibutton(app.OperationSamplingPanel, 'push');
            app.downButton.ButtonPushedFcn = createCallbackFcn(app, @downButtonPushed, true);
            app.downButton.Icon = 'downArrow-1.jpg';
            app.downButton.Text = '';
            app.upButton = uibutton(app.OperationSamplingPanel, 'push');
            app.upButton.ButtonPushedFcn = createCallbackFcn(app, @upButtonPushed, true);
            app.upButton.Icon = 'upArrow-1.jpg';
            app.upButton.Text = '';
            app.leftButton = uibutton(app.OperationSamplingPanel, 'push');
            app.leftButton.ButtonPushedFcn = createCallbackFcn(app, @leftButtonPushed, true);
            app.leftButton.Icon = 'leftArrow-1.jpg';
            app.leftButton.Text = '';
            app.rightButton = uibutton(app.OperationSamplingPanel, 'push');
            app.rightButton.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.rightButton.Icon = 'rightArrow-1.jpg';
            app.rightButton.Text = '';
            app.AngleEditFieldLabel_2 = uilabel(app.OperationSamplingPanel);
            app.AngleEditFieldLabel_2.Text = 'Angle';
            app.leftRightTiltAngleEdit = uieditfield(app.OperationSamplingPanel, 'numeric');
            app.leftRightTiltAngleEdit.ValueChangedFcn = createCallbackFcn(app, @leftRightTiltAngleEditValueChanged, true);
            app.antiClockButton = uibutton(app.OperationSamplingPanel, 'push');
            app.antiClockButton.ButtonPushedFcn = createCallbackFcn(app, @antiClockButtonPushed, true);
            app.antiClockButton.Icon = 'antiClockwiseArrow-1.jpg';
            app.antiClockButton.Text = '';
            app.clockButton = uibutton(app.OperationSamplingPanel, 'push');
            app.clockButton.ButtonPushedFcn = createCallbackFcn(app, @clockButtonPushed, true);
            app.clockButton.Icon = 'clockwiseArrow-1.jpg';
            app.clockButton.Text = '';
            app.AngleEditFieldLabel_3 = uilabel(app.OperationSamplingPanel);
            app.AngleEditFieldLabel_3.Text = 'Angle';
            app.inPlaneTiltAngleEdit = uieditfield(app.OperationSamplingPanel, 'numeric');
            app.inPlaneTiltAngleEdit.ValueChangedFcn = createCallbackFcn(app, @inPlaneTiltAngleEditValueChanged, true);
            app.BackToInitialButton = uibutton(app.OperationSamplingPanel, 'push');
            app.BackToInitialButton.ButtonPushedFcn = createCallbackFcn(app, @BackToInitialButtonPushed, true);
            app.BackToInitialButton.Text = 'Back to initial';
            app.PixelsizeAngsEditFieldLabel = uilabel(app.OperationSamplingPanel);
            app.PixelsizeAngsEditFieldLabel.Text = 'Pixel size (Angs.)';
            app.pixelSizeEdit = uieditfield(app.OperationSamplingPanel, 'numeric');
            app.pixelSizeEdit.ValueChangedFcn = createCallbackFcn(app, @pixelSizeEditValueChanged, true);
            app.PixelnumberperdimensionEditFieldLabel = uilabel(app.OperationSamplingPanel);
            app.PixelnumberperdimensionEditFieldLabel.Text = 'Pixel number per dimension';
            app.dimPixelNumEdit = uieditfield(app.OperationSamplingPanel, 'numeric');
            app.dimPixelNumEdit.ValueChangedFcn = createCallbackFcn(app, @dimPixelNumEditValueChanged, true);
            app.MaxanglemradEditFieldLabel = uilabel(app.OperationSamplingPanel);
            app.MaxanglemradEditFieldLabel.Text = 'Max angle (mrad)';
            app.maxAngleEdit = uieditfield(app.OperationSamplingPanel, 'numeric');
            app.maxAngleEdit.ValueChangedFcn = createCallbackFcn(app, @maxAngleEditValueChanged, true);
            app.AngularprecisionmradEditFieldLabel = uilabel(app.OperationSamplingPanel);
            app.AngularprecisionmradEditFieldLabel.Text = 'Angular precision (mrad)';
            app.angPrecEdit = uieditfield(app.OperationSamplingPanel, 'numeric');
            app.angPrecEdit.ValueChangedFcn = createCallbackFcn(app, @angPrecEditValueChanged, true);
            app.defaultSampButton = uibutton(app.OperationSamplingPanel, 'push');
            app.defaultSampButton.ButtonPushedFcn = createCallbackFcn(app, @defaultSampButtonPushed, true);
            app.defaultSampButton.Text = {'Generate default '; 'sampling'};
            app.projPotButton = uibutton(app.OperationSamplingPanel, 'push');
            app.projPotButton.ButtonPushedFcn = createCallbackFcn(app, @projPotButtonPushed, true);
            app.projPotButton.Text = {'Generate specimen'; 'projected potential'};
            app.SpecimenprojectedpotentialpreparedLabel = uilabel(app.OperationSamplingPanel);
            app.SpecimenprojectedpotentialpreparedLabel.Text = {'Specimen projected'; 'potential prepared'};
            app.projPotPrepareLamp = uilamp(app.OperationSamplingPanel);
            app.ProjectedpotentialupdatetimeLabel = uilabel(app.OperationSamplingPanel);
            app.ProjectedpotentialupdatetimeLabel.Text = {'Projected potential '; 'update time'};
            app.projPotLastUpdateTimeEdit = uieditfield(app.OperationSamplingPanel, 'text');
            app.hklOrientButton = uibutton(app.OperationSamplingPanel, 'push');
            app.hklOrientButton.Text = 'Orientation (hkl)';
            % Show the figure after all components are created
            app.NanoDiffUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NanoDiff

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NanoDiffUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NanoDiffUIFigure)
        end
    end
end